classdef datasheetDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DatasheetSelectionUIFigure  matlab.ui.Figure
        DatasheetTextAreaLabel      matlab.ui.control.Label
        DatasheetTextArea           matlab.ui.control.TextArea
        BrowseButton                matlab.ui.control.Button
        OKButton                    matlab.ui.control.Button
        DatasheetcanbeeitherafilesavedonthelocalcomputeroraURLLabel  matlab.ui.control.Label
        DialogTextAreaLabel         matlab.ui.control.Label
        DialogTextArea              matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        callingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialize(app, callingApp)
            app.callingApp = callingApp;
        end

        % Button pushed function: BrowseButton
        function browseFiles(app, event)
            [FILENAME, PATHNAME, FILTERINDEX] = uigetfile('*.pdf', 'Datasheet PDF');
            app.DatasheetTextArea.Value = {[PATHNAME FILENAME]};
        end

        % Button pushed function: OKButton
        function okButton(app, event)
            datasheet = char(app.DatasheetTextArea.Value);
        
            if isempty(datasheet) || (length(datasheet) < 5)
                app.DialogTextArea.Value = {'datasheet cannot be left blank'};
                app.DialogTextArea.Visible = true;
                return;
            end
            
            if (~strcmp('.pdf',datasheet(end-3:end)))
                app.DialogTextArea.Value = {'datasheet must be direct link to a PDF'};
                app.DialogTextArea.Visible = true;
                return;
            end
    
            if (~strcmp('http',datasheet(1:4))) && ~isfile(datasheet)
                app.DialogTextArea.Value = {'specified file does not exist'};
                app.DialogTextArea.Visible = true;
                return;
            end
            
            if (strcmp('http',datasheet(1:4))) 
                loc = find(datasheet == '/', 1, 'last');
                fn = ['temp\' datasheet(loc+1:end)];
                if ~isfile(fn)
                    try
                        outfilename = websave(fn,datasheet);
                    catch
                        app.DialogTextArea.Value = {'unable to dowload pdf from URL specified'};
                        app.DialogTextArea.Visible = true;    
                        return;
                    end
                end

            end
            
            app.callingApp.datasheet = datasheet;
            app.callingApp.dialogWait = 0;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DatasheetSelectionUIFigure = uifigure('Visible', 'off');
            app.DatasheetSelectionUIFigure.Name = 'Datasheet Selection';
            app.DatasheetTextAreaLabel = uilabel(app.DatasheetSelectionUIFigure);
            app.DatasheetTextAreaLabel.Text = 'Datasheet';
            app.DatasheetTextArea = uitextarea(app.DatasheetSelectionUIFigure);
            app.BrowseButton = uibutton(app.DatasheetSelectionUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @browseFiles, true);
            app.BrowseButton.Text = 'Browse';
            app.OKButton = uibutton(app.DatasheetSelectionUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @okButton, true);
            app.OKButton.Text = 'OK';
            app.DatasheetcanbeeitherafilesavedonthelocalcomputeroraURLLabel = uilabel(app.DatasheetSelectionUIFigure);
            app.DatasheetcanbeeitherafilesavedonthelocalcomputeroraURLLabel.Text = 'Datasheet can be either a file saved on the local computer, or a URL.  Must be a PDF';
            app.DialogTextAreaLabel = uilabel(app.DatasheetSelectionUIFigure);
            app.DialogTextAreaLabel.Visible = 'off';
            app.DialogTextAreaLabel.Text = 'Dialog';
            app.DialogTextArea = uitextarea(app.DatasheetSelectionUIFigure);
            app.DialogTextArea.Visible = 'off';
            % Show the figure after all components are created
            app.DatasheetSelectionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = datasheetDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DatasheetSelectionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)initialize(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DatasheetSelectionUIFigure)
        end
    end
end