classdef PDpreferences < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PlotDigitizerPreferencesUIFigure  matlab.ui.Figure
        RectSelectionLabel             matlab.ui.control.Label
        useRectSelection               matlab.ui.control.CheckBox
        CVToolboxLabel                 matlab.ui.control.Label
        ImageToolboxLabel              matlab.ui.control.Label
        useCVToolbox                   matlab.ui.control.CheckBox
        useImageToolbox                matlab.ui.control.CheckBox
        egTextArea                     matlab.ui.control.TextArea
        egTextAreaLabel                matlab.ui.control.Label
        DialogTextArea                 matlab.ui.control.TextArea
        DialogTextAreaLabel            matlab.ui.control.Label
        egCProgramFilesgsgs952bingswin64exeLabel_3  matlab.ui.control.Label
        GhostscriptparametersTextArea  matlab.ui.control.TextArea
        GhostscriptparametersTextAreaLabel  matlab.ui.control.Label
        OKButton                       matlab.ui.control.Button
        egCProgramFilesgsgs952bingswin64exeLabel_2  matlab.ui.control.Label
        GetGhostscriptButton           matlab.ui.control.Button
        egCProgramFilesgsgs952bingswin64exeLabel  matlab.ui.control.Label
        GhostscriptexecutableTextArea  matlab.ui.control.TextArea
        GhostscriptexecutableTextAreaLabel  matlab.ui.control.Label
        BrowseButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        callingApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialize(app, callingApp)
            app.callingApp = callingApp;
            %% Load in stored settings
            settingsLoc = fullfile(userpath, 'SMPSToolbox', 'PlotDigitizer', 'Settings', 'digitizerSetup.mat');
            if exist(settingsLoc,'file')
                load(settingsLoc, 'PDsettings');
                if exist('PDsettings', 'var')
                    if isfield(PDsettings, 'gsloc')
                        app.GhostscriptexecutableTextArea.Value = PDsettings.gsloc;
                    end
                    if isfield(PDsettings, 'clargs')
                        app.GhostscriptparametersTextArea.Value = PDsettings.clargs;
                    end
                    if isfield(PDsettings, 'useCVToolbox')
                        app.useCVToolbox.Value = PDsettings.useCVToolbox;
                    end
                    if isfield(PDsettings, 'useImageToolbox')
                        app.useImageToolbox.Value = PDsettings.useImageToolbox;
                    end
                    if isfield(PDsettings, 'useRectSelection')
                        app.useRectSelection.Value = PDsettings.useRectSelection;
                    end
                end
            end
            
            % [flist, plist] = matlab.codetools.requiredFilesAndProducts('plotDigitizer.mlapp') 
            
            %% Check MATLAB version and installed Toolboxes
            v = ver;
            hasImageToolbox = license('test','image_toolbox') && ~isempty(intersect({v.Name}, 'Image Processing Toolbox'));
            hasComputerVision = license('test','Video_and_Image_Blockset') && ~isempty(intersect({v.Name}, 'Computer Vision Toolbox'));
            MLver = version;
            [si, ei] = regexp(MLver, '\(.*\)');
            MLversion = {str2double(MLver(si+2:ei-2)), MLver(ei-1)};
            
            canUseRectSelection = (MLversion{1} > 2019 || ...
                ((MLversion{1} == 2019) && MLversion{2} == 'b')) ...
                && hasImageToolbox;
            
            %% Block out incompatible fields
            if ~hasImageToolbox
                app.useImageToolbox.Value = 0;
                app.useImageToolbox.Enable = false;
                app.ImageToolboxLabel.Text = ['This setting can only be ' ...
                    'enabled if the Image Processing Toolbox is installed.'];
            end
            if ~canUseRectSelection
                app.useRectSelection.Value = 0;
                app.useRectSelection.Enable = false;
                app.RectSelectionLabel.Text = ['This setting can only be ' ...
                    'enabled if using a MATLAB version greater than 2019b' ...
                    newline 'and the Image Processing Toolbox is installed.'];
            end
            if ~hasComputerVision
                app.useCVToolbox.Value = 0;
                app.useCVToolbox.Enable = false;
                app.CVToolboxLabel.Text = ['This setting can only be ' ...
                    'enabled if the Computer Vision Toolbox is Installed'];
            end
            
        end

        % Button pushed function: BrowseButton
        function browseFiles(app, event)
            [FILENAME, PATHNAME, ~] = uigetfile('*.exe', 'Ghostscript Executable');
            app.GhostscriptexecutableTextArea.Value = {[PATHNAME FILENAME]};
        end

        % Button pushed function: GetGhostscriptButton
        function getGSdownload(app, event)
            web('https://www.ghostscript.com/download/gsdnld.html');
        end

        % Button pushed function: OKButton
        function closeButton(app, event)
            settingsLoc = fullfile(userpath, 'SMPSToolbox', 'PlotDigitizer', 'Settings', 'digitizerSetup.mat');
            if exist(settingsLoc,'file')
                load(settingsLoc, 'PDsettings');
                oldSettings = PDsettings;
                clear PDsettings;
            else
                oldSettings = {};
            end
            
            PDsettings.useCVToolbox = app.useCVToolbox.Value;
            PDsettings.useImageToolbox = app.useImageToolbox.Value;
            PDsettings.useRectSelection = app.useRectSelection.Value;
            if isempty(app.GhostscriptexecutableTextArea.Value{1})
                app.DialogTextArea.Value = {'Ghostscript executable field cannot be empty'};
                app.DialogTextArea.Visible = true;
                return
            end
            
            PDsettings.gsloc = char(app.GhostscriptexecutableTextArea.Value);
            % if ~isempty(app.GhostscriptparametersTextArea.Value{1})
                PDsettings.clargs = char(app.GhostscriptparametersTextArea.Value);
            % end
            
            
            if ~isempty(oldSettings) && ...
                    numel(fieldnames(oldSettings)) ==  numel(fieldnames(PDsettings)) && ...
                    sum(strcmp(fieldnames(oldSettings), fieldnames(PDsettings))) == length(fieldnames(PDsettings))
                if oldSettings.useCVToolbox == PDsettings.useCVToolbox && ...
                    oldSettings.useImageToolbox == PDsettings.useImageToolbox && ...
                    strcmp(oldSettings.gsloc, PDsettings.gsloc) == 1 && ...
                    strcmp(oldSettings.clargs, PDsettings.clargs) == 1 && ...
                    oldSettings.useRectSelection == PDsettings.useRectSelection
                    %% no changes made
                        app.callingApp.dialogWait = 0;
                        delete(app);
                        return
                end
            end
                    
            %% otherwise, we've changed something.     
            isFolder = dir(fullfile(userpath, 'SMPSToolbox', 'PlotDigitizer', 'Settings'));
            if isempty(isFolder)
                mkdir(fullfile(userpath, 'SMPSToolbox', 'PlotDigitizer', 'Settings'))
            end
            save(settingsLoc, 'PDsettings');
%             datasheet = app.callingApp.datasheet;
%             delete(app.callingApp);
%             plotDigitizer(datasheet);
            app.callingApp.reinitialize();
            app.callingApp.dialogWait = 0;
            delete(app);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PlotDigitizerPreferencesUIFigure = uifigure('Visible', 'off');
            app.PlotDigitizerPreferencesUIFigure.Name = 'Plot Digitizer Preferences';
            app.BrowseButton = uibutton(app.PlotDigitizerPreferencesUIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @browseFiles, true);
            app.BrowseButton.Text = 'Browse';
            app.GhostscriptexecutableTextAreaLabel = uilabel(app.PlotDigitizerPreferencesUIFigure);
            app.GhostscriptexecutableTextAreaLabel.Text = 'Ghostscript executable';
            app.GhostscriptexecutableTextArea = uitextarea(app.PlotDigitizerPreferencesUIFigure);
            app.egCProgramFilesgsgs952bingswin64exeLabel = uilabel(app.PlotDigitizerPreferencesUIFigure);
            app.egCProgramFilesgsgs952bingswin64exeLabel.Text = 'Location of Ghostscript executeable, used to read PDF datasheets.';
            app.GetGhostscriptButton = uibutton(app.PlotDigitizerPreferencesUIFigure, 'push');
            app.GetGhostscriptButton.ButtonPushedFcn = createCallbackFcn(app, @getGSdownload, true);
            app.GetGhostscriptButton.Text = 'Get Ghostscript';
            app.egCProgramFilesgsgs952bingswin64exeLabel_2 = uilabel(app.PlotDigitizerPreferencesUIFigure);
            app.egCProgramFilesgsgs952bingswin64exeLabel_2.Text = 'If you don''t have Ghostscript, it can be downloaded for free here:';
            app.OKButton = uibutton(app.PlotDigitizerPreferencesUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @closeButton, true);
            app.OKButton.Text = 'OK';
            app.GhostscriptparametersTextAreaLabel = uilabel(app.PlotDigitizerPreferencesUIFigure);
            app.GhostscriptparametersTextAreaLabel.Text = 'Ghostscript parameters';
            app.GhostscriptparametersTextArea = uitextarea(app.PlotDigitizerPreferencesUIFigure);
            app.GhostscriptparametersTextArea.Value = {'-sDEVICE=pngalpha -r300 -o'};
            app.egCProgramFilesgsgs952bingswin64exeLabel_3 = uilabel(app.PlotDigitizerPreferencesUIFigure);
            app.egCProgramFilesgsgs952bingswin64exeLabel_3.Tooltip = {''};
            app.egCProgramFilesgsgs952bingswin64exeLabel_3.Text = {'Any additional command line arguments to pass to Ghostscript.  '; 'Leave as-is if unknown.'};
            app.DialogTextAreaLabel = uilabel(app.PlotDigitizerPreferencesUIFigure);
            app.DialogTextAreaLabel.Visible = 'off';
            app.DialogTextAreaLabel.Text = 'Dialog';
            app.DialogTextArea = uitextarea(app.PlotDigitizerPreferencesUIFigure);
            app.DialogTextArea.Visible = 'off';
            app.egTextAreaLabel = uilabel(app.PlotDigitizerPreferencesUIFigure);
            app.egTextAreaLabel.Text = 'e.g.';
            app.egTextArea = uitextarea(app.PlotDigitizerPreferencesUIFigure);
            app.egTextArea.Editable = 'off';
            app.egTextArea.Value = {'C:\Program Files\gs\gs9.52\bin\gswin64.exe'};
            app.useImageToolbox = uicheckbox(app.PlotDigitizerPreferencesUIFigure);
            app.useImageToolbox.Text = 'Use Image Processing Toolbox';
            app.useImageToolbox.Value = true;
            app.useCVToolbox = uicheckbox(app.PlotDigitizerPreferencesUIFigure);
            app.useCVToolbox.Text = 'Use Computer Vision Toolbox';
            app.useCVToolbox.Value = true;
            app.ImageToolboxLabel = uilabel(app.PlotDigitizerPreferencesUIFigure);
            app.ImageToolboxLabel.Tooltip = {''};
            app.ImageToolboxLabel.Text = {'Image processing toolbox used to make snipping from PDF pages easier and to digitize plots automatically.'; 'Requires the toolbox be installed and MATLAB version is 2019b or later'};
            app.CVToolboxLabel = uilabel(app.PlotDigitizerPreferencesUIFigure);
            app.CVToolboxLabel.Tooltip = {''};
            app.CVToolboxLabel.Text = {'Computer Vision Toolbox is used to automatically detect text characters on plots.  Required the toolbox'; 'be installed'};
            app.useRectSelection = uicheckbox(app.PlotDigitizerPreferencesUIFigure);
            app.useRectSelection.Text = 'Use Dynamic Rectangle Selection';
            app.useRectSelection.Value = true;
            app.RectSelectionLabel = uilabel(app.PlotDigitizerPreferencesUIFigure);
            app.RectSelectionLabel.Tooltip = {''};
            app.RectSelectionLabel.Text = 'Makes snipping from pdf pages easier.  Requires image toolbox and MATLAB version 2019b or later';
            % Show the figure after all components are created
            app.PlotDigitizerPreferencesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PDpreferences(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PlotDigitizerPreferencesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)initialize(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PlotDigitizerPreferencesUIFigure)
        end
    end
end