classdef plotDigitizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DatasheetDigitizerUIFigure    matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        NewMenu                       matlab.ui.container.Menu
        PreferencesMenu               matlab.ui.container.Menu
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        TestConditionsPanel           matlab.ui.container.Panel
        AddButton                     matlab.ui.control.Button
        TraceDropDown                 matlab.ui.control.DropDown
        TraceDropDownLabel            matlab.ui.control.Label
        ConditionUnitEditField        matlab.ui.control.EditField
        ConditionSIPrefixDropDown     matlab.ui.control.DropDown
        ConditionValueEditField       matlab.ui.control.NumericEditField
        ConditionEqualsSign           matlab.ui.control.EditField
        ConditionSignalTree           matlab.ui.container.Tree
        digitizedResultsCancelButton  matlab.ui.control.Button
        FindSimilarPlotsButton        matlab.ui.control.Button
        digitizedResultsDoneButton    matlab.ui.control.Button
        digitizedResultsTable         matlab.ui.control.Table
        DatasheetLabel                matlab.ui.control.Label
        nextButton                    matlab.ui.control.Button
        PartNumberEditField           matlab.ui.control.EditField
        PartNumberEditFieldLabel      matlab.ui.control.Label
        InstructionsTextArea          matlab.ui.control.TextArea
        InstructionsTextAreaLabel     matlab.ui.control.Label
        prevButton                    matlab.ui.control.Button
        pdfImage                      matlab.ui.control.Image
        pdfAxes                       matlab.ui.control.UIAxes
        RightPanel                    matlab.ui.container.Panel
        IdentifyPlotButton            matlab.ui.control.Button
        YaxisPlotLimitsPanel          matlab.ui.container.Panel
        YtuningButtonGroup            matlab.ui.container.ButtonGroup
        YminButton                    matlab.ui.control.ToggleButton
        YmaxButton                    matlab.ui.control.ToggleButton
        yAxisSlider                   matlab.ui.control.Slider
        TunePlotLimitsButton          matlab.ui.control.Button
        XaxisPlotLimitsPanel          matlab.ui.container.Panel
        XtuningButtonGroup            matlab.ui.container.ButtonGroup
        XmaxButton                    matlab.ui.control.ToggleButton
        XminButton                    matlab.ui.control.ToggleButton
        xAxisSlider                   matlab.ui.control.Slider
        YaxisCalibrationPanel         matlab.ui.container.Panel
        YSIprefixDropDown             matlab.ui.control.DropDown
        SIprefixDropDown_2Label_2     matlab.ui.control.Label
        MinYValueEditField            matlab.ui.control.NumericEditField
        MinYValueEditFieldLabel       matlab.ui.control.Label
        MaxYValueEditField            matlab.ui.control.NumericEditField
        MaxYValueEditFieldLabel       matlab.ui.control.Label
        YaxisLabel                    matlab.ui.control.Label
        YaxisTree                     matlab.ui.container.Tree
        LogarithmicYaxisCheckBox      matlab.ui.control.CheckBox
        NormalizedYaxisCheckBox       matlab.ui.control.CheckBox
        XaxisCalibrationPanel         matlab.ui.container.Panel
        MinXValueEditField            matlab.ui.control.NumericEditField
        MinXValueEditFieldLabel       matlab.ui.control.Label
        MaxXValueEditField            matlab.ui.control.NumericEditField
        MaxXValueEditFieldLabel       matlab.ui.control.Label
        XSIprefixDropDown             matlab.ui.control.DropDown
        SIprefixDropDown_2Label       matlab.ui.control.Label
        LogarithmicXaxisCheckBox      matlab.ui.control.CheckBox
        NormalizedXaxisCheckBox       matlab.ui.control.CheckBox
        XaxisTree                     matlab.ui.container.Tree
        UnusedComponentsPanel         matlab.ui.container.Panel
        YaxisDropDown                 matlab.ui.control.DropDown
        YaxisDropDownLabel            matlab.ui.control.Label
        XaxisDropDown                 matlab.ui.control.DropDown
        XaxisDropDownLabel            matlab.ui.control.Label
        skewedaxesCheckBox            matlab.ui.control.CheckBox
        AutoDigitizeBWButton          matlab.ui.control.Button
        AutoDigitizebyColorButton     matlab.ui.control.Button
        AutoDigitizeButton            matlab.ui.control.Button
        XaxisLabel                    matlab.ui.control.Label
        TransistorDatabaseLabel       matlab.ui.control.Label
        DBpartsTree                   matlab.ui.container.Tree
        stateEditField                matlab.ui.control.NumericEditField
        stateEditFieldLabel           matlab.ui.control.Label
        ShowPlotMasksButton           matlab.ui.control.StateButton
        SaveButton                    matlab.ui.control.Button
        NTracesEditField              matlab.ui.control.NumericEditField
        ofTracesLabel                 matlab.ui.control.Label
        digiTracesTable               matlab.ui.control.Table
        plotImage                     matlab.ui.control.Image
        DigitizeManuallyButton        matlab.ui.control.Button
        plotAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (SetAccess = private)
        debug = 0
        settings
        
        dialogMessage
        
        pdfFiles % cell array of filenames of all png pages of pdf datasheet
        pdfIndex = 1
        
        pdfClicks
%         clickTimer = 0 % timer between two clicks
        
%         pdfSelectionRect % Description
        pdfOrigPosition
        plotOrigPosition
        
        pdfOrigImage
        pdfMask = 0
        otherPlotBounds
        
%         showPlotMask = true;
        plotMask = 0
        
        displayPoints % Markers for post-digized table data selection
        
        curPlotData 
        savedPlotData
        partDB
    end
    
    properties (SetAccess=private)
        state
    end
    
    properties (Hidden, Constant)
       states = struct('initialize', 1, 'plotSelection', 2, 'findAxes', 3, 'axesCalibration', 4, 'axesValid', 5, 'autoDigitizing', 6, 'manuallyDigitizing', 7, 'dataReview', 8, 'curveLabeling', 9, 'testConditions', 10)
       componentType = smps.components.transistor()
       stateMsg = struct('initialize', 'Initializing', ...
           'plotSelection', 'Select plot from datasheet', ...
           'findAxes', 'Locating axes on selected plot', ...
           'axesCalibration', 'Labeling axes', ...
           'axesValid', 'Waiting for digitization', ...
           'autoDigitizing', 'Digitizing automatically', ...
           'manuallyDigitizing', 'Digitizing manually', ...
           'dataReview', 'Review digitized data', ...
           'curveLabeling', 'Label curve signals', ...
           'testConditions', 'Add test conditions (if present)')
    end
    
    properties (Access = public)
        dialogWait = 0 % flag to indicate that a dialog window is open
        datasheet 
    end
    
    methods
        function set.state(app, newState)
            % set.state(app, newState) handles the internal state machine
            % which dictates which components are visible/enabled

           app.FindSimilarPlotsButton.Enable = (newState >= app.states.findAxes &&  newState <= app.states.axesValid );
           app.FindSimilarPlotsButton.Visible = app.FindSimilarPlotsButton.Enable;
           app.DigitizeManuallyButton.Enable = (newState == app.states.axesValid);
           app.AutoDigitizebyColorButton.Enable = (newState == app.states.axesValid);
           app.AutoDigitizeBWButton.Enable = (newState == app.states.axesValid);
           app.AutoDigitizeButton.Enable = (newState == app.states.axesValid);
           
           app.TunePlotLimitsButton.Enable = (newState >= app.states.findAxes &&  newState <= app.states.axesValid );
           
           app.digitizedResultsDoneButton.Visible = (newState >= app.states.dataReview);
           app.digitizedResultsCancelButton.Visible = (newState >= app.states.dataReview);
           app.digitizedResultsTable.Visible = (newState == app.states.dataReview);
           
           app.TestConditionsPanel.Visible = (newState >= app.states.curveLabeling);
           
           %% Turn off changing the axes calibration if we've already started digitizing
           canChangeAxes = (newState <= app.states.axesValid);
           app.MaxYValueEditField.Enable = canChangeAxes;
           app.MinYValueEditField.Enable = canChangeAxes;
           app.MaxXValueEditField.Enable = canChangeAxes;
           app.MinXValueEditField.Enable = canChangeAxes;
           app.XSIprefixDropDown.Enable = canChangeAxes;
           app.YSIprefixDropDown.Enable = canChangeAxes;
           app.LogarithmicYaxisCheckBox.Enable = canChangeAxes;
           app.LogarithmicXaxisCheckBox.Enable = canChangeAxes;
           app.NormalizedYaxisCheckBox.Enable = canChangeAxes;
           app.NormalizedXaxisCheckBox.Enable = canChangeAxes;
           
           %% if we dropped down in state, show the masks again to make sure axes are correct
           if newState == app.states.findAxes && app.state > app.states.findAxes
               app.ShowPlotMasksButton.Value = true;
           end
           
           app.state = newState;
           
           app.stateEditField.Value = newState;
           stateNames = fieldnames(app.states);
           app.InstructionsTextArea.Value = app.stateMsg.(stateNames{newState});
           if (isempty(app.PartNumberEditField.Value))
               app.InstructionsTextArea.Value = [app.InstructionsTextArea.Value; ' '; 'Enter part number at top of left panel.'; ];
           end
           app.InstructionsTextArea.FontColor = [0 0 0];
           
%            app.state = app.states.initialize;
%            app.state = app.states.plotSelection;
%            app.state = app.states.findAxes;
%            app.state = app.states.axesCalibration;
%            app.state = app.states.axesValid;
%            app.state = app.states.autoDigitizing;
%            app.state = app.states.manuallyDigitizing;
%            app.state = app.states.dataReview;
%            app.state = app.states.curveLabeling;

%            app.FindSimilarPlotsButton.Enable = "on";
%            app.checkValidForDigitizing()
%            app.digitizedResultsTable.Visible = "on";
%            app.digitizedResultsDoneButton.Visible = "on";
%            app.digitizedResultsCancelButton.Visible = "on";
%            app.digitizedResultsTable.Data = [];
%            app.TestConditionsPanel.Visible = "on";
        end
        
    end
    
    methods (Access = private)
        
        function findPDFfiles(app)    
            tempDir = fullfile(userpath, 'SMPSToolbox', 'PlotDigitizer', 'temp');
            S = dir(fullfile(tempDir,'datasheet-p*.png'));
            app.pdfFiles = {S.name};
            if ~isempty(app.pdfFiles)
                app.pdfIndex = 1;
                app.pdfOrigImage = imread(app.pdfFiles{app.pdfIndex});
                app.showDatasheetPage();
            else
                app.pdfIndex = 0;
            end
        end
        
        function plotSelectionFromPDF(app)
            pageData = app.pdfOrigImage;
            app.plotMask = 0;
            
            cols = sort(round(size(pageData,2)*(app.pdfClicks(:,1))));
            rows = sort(round(size(pageData,1)*(1-app.pdfClicks(:,2))));
 
            imgSelection = pageData(rows(1):rows(2), cols(1):cols(2),:);
            app.curPlotData = digitizedPlot(app, imgSelection, [cols(1), rows(1), diff(cols), diff(rows)], app.pdfIndex);
            
            app.identifyPlot();
%             app.FindSimilarPlotsButton.Enable = "on";
            
        end
        
        function loadDatasheetPDF(app)

            tempDir = fullfile(userpath, 'SMPSToolbox', 'PlotDigitizer', 'temp');
            
            if ~strcmp(app.datasheet(end-3:end), '.pdf')
                error('Datasheet given must be a direct link to a PDF')
            end
            
            if strcmp(app.datasheet(1:4), 'http')
                % first check if it is already downloaded
                loc = find(app.datasheet == '/', 1, 'last');
                fn = fullfile(tempDir, app.datasheet(loc+1:end));
                if ~isfile(fn)
                    outfilename = websave(fn,app.datasheet);
                    app.datasheet = outfilename;
                else
                    %already downloaded it
                    app.datasheet = fn;
                end
                
            end

            %% Parse PDF into individual page images
            % First delete any old ones
            if(isfile(app.datasheet))
                app.findPDFfiles;
                for i = 1:length(app.pdfFiles)
                    fn = fullfile(tempDir, app.pdfFiles{i});
                    delete(fn);
    %                 display(['temp\' app.pdfFiles{i}])
                end
            end
            
            % Then create pngs of each page
            tempDir = fullfile(userpath, 'SMPSToolbox', 'PlotDigitizer', 'temp');
            pagesOut = fullfile(tempDir, 'datasheet-p%03d.png');
            if (contains(char(app.settings.clargs), ' -o ') || endsWith(char(app.settings.clargs), ' -o') ) && contains(char(app.settings.clargs), '-sDEVICE')
                cmd = ['"' char(app.settings.gsloc) '" ' char(app.settings.clargs) ' "' pagesOut '" "' char(app.datasheet) '"'];
            else
                % Batch conversion needs these switches at minimum to work
                addlArg = '';
                if ~(contains(char(app.settings.clargs), ' -o ') || endsWith(char(app.settings.clargs), ' -o') )
                    addlArg{1} = '-o';
                end
                if ~contains(char(app.settings.clargs), '-sDEVICE')
                    addlArg{end+1} = '-sDEVICE=pngalpha';
                end
                addlArg = strjoin(addlArg, ' ');

                cmd = ['"' char(app.settings.gsloc) '" ' char(app.settings.clargs) ' ' addlArg ' "' pagesOut '" "' char(app.datasheet) '"'];
            end

            system(cmd);
            
            app.pdfOrigImage = imread(fullfile(tempDir, 'datasheet-p001.png'));
            app.showDatasheetPage();
        end
        
        function identifyPlot(app)
            app.curPlotData.loadAppData();
            foundAxes = app.curPlotData.findPlotArea();
    
            if length(foundAxes) == 4
                maskedImg = app.curPlotData.axesMask();
                app.plotMask = double(maskedImg) - double(app.curPlotData.img);

                %% Search for axis max/min
                if(app.settings.useCVToolbox)
                    try
                        maskedImg = app.curPlotData.OCRaxes(maskedImg);
                        app.plotMask = double(maskedImg) - double(app.curPlotData.img);
                    catch ME
                        if app.debug == 1
                            warning("OCR failed, attempting to continue.")
                            getReport(ME)
                        end
                    end
                end
                app.showPlot();
                app.checkValidForDigitizing();
                app.state = app.states.axesCalibration;
            end
        end
        
        function showDatasheetPage(app)

            img = uint8(double(app.pdfOrigImage) + app.pdfMask);
%             img_ar = size(img,1)/size(img,2); %h/w
            
            if app.settings.useRectSelection
%                 fig_ar = app.pdfAxes.Position(4)/app.pdfAxes.Position(3);
%                 if abs(img_ar - fig_ar) > 1/app.pdfAxes.Position(3)
%                     %% Aspect ratio is off -- correct it
%                     if img_ar > fig_ar %img is too tall -- reduce width
%                         app.pdfAxes.Position(3) = round(app.pdfAxes.Position(4)/img_ar);
%                     else
%                         app.pdfAxes.Position(4) = round(app.pdfAxes.Position(3)*img_ar);
%                     end
%                 end
                app.resizeToAspectRatio(app.pdfAxes, img, app.pdfOrigPosition);
                
                %%Check if there is already a rectangle
                for i = 1:length(app.pdfAxes.Children)
                    if strcmp(class(app.pdfAxes.Children(i)), 'images.roi.Rectangle')
                        rectloc = app.pdfAxes.Children(i).Position;
                    end
                end
                
                I = imshow(img, 'Parent', app.pdfAxes);
                app.pdfAxes.XLim = I.XData;
                app.pdfAxes.YLim = I.YData;
                
                if(exist('rectloc','var'))
                    h = drawrectangle(app.pdfAxes, 'Position', rectloc);
                else
                    h = drawrectangle(app.pdfAxes, 'Position', [.25*size(app.pdfOrigImage, [ 1 2]), .25*size(app.pdfOrigImage, [ 1 2])]);
                end
                
                h.LineWidth = 1;
                addprop(h, 'callingApp');
                h.callingApp = app;
                addlistener(h, 'ROIMoved', @pdfSelectionRectMoved);
                app.pdfSelectionRectMoved(h);
           
            else
%                 fig_ar = app.pdfOrigPosition(4)/app.pdfOrigPosition(3);
%                 if abs(img_ar - fig_ar) > 1/app.pdfOrigPosition(3)
%                     %% Aspect ratio is off -- correct it
%                     if img_ar > fig_ar %img is too tall -- reduce width
%                         app.pdfImage.Position(4) = app.pdfOrigPosition(4);
%                         app.pdfImage.Position(3) = round(app.pdfOrigPosition(4)/img_ar);
%                     else
%                         app.pdfImage.Position(3) = app.pdfOrigPosition(3);
%                         app.pdfImage.Position(4) = round(app.pdfOrigPosition(3)*img_ar);
%                     end
%                 end
                app.resizeToAspectRatio(app.pdfImage, img, app.pdfOrigPosition);
                app.pdfImage.ImageSource = img;
            end
           app.state = app.states.plotSelection;
        end
        
        function showPlot(app)
            if(app.ShowPlotMasksButton.Value == true)
                img = uint8(double(app.curPlotData.img) + app.plotMask);
            else
                 img = app.curPlotData.img;
            end
%             img_ar = size(img,1)/size(img,2); %h/w
% 
%             fig_ar = app.plotOrigPosition(4)/app.plotOrigPosition(3);
%             if abs(img_ar - fig_ar) > 1/app.plotOrigPosition(3)
%                 %% Aspect ratio is off -- correct it
%                 if img_ar > fig_ar %img is too tall -- reduce width
%                     app.plotImage.Position(4) = app.plotOrigPosition(4);
%                     app.plotImage.Position(3) = round(app.plotOrigPosition(4)/img_ar);
%                 else
%                     app.plotImage.Position(3) = app.plotOrigPosition(3);
%                     app.plotImage.Position(4) = round(app.plotOrigPosition(3)*img_ar);
%                 end
%             end
            app.resizeToAspectRatio(app.plotImage, img, app.plotOrigPosition);
            app.plotImage.ImageSource = img;
            app.plotImage.Visible = true;
            app.state = app.states.findAxes;
        end
        
        function checkValidForDigitizing(app)
            valid = true;
            
            if ~isempty(intersect(app.XaxisTree.Children, app.XaxisTree.SelectedNodes))
                valid = false;
                app.DigitizeManuallyButton.Tooltip = 'x-axis label must be a specific signal';
                app.AutoDigitizebyColorButton.Tooltip = 'x-axis label must be a specific signal';
            end
            
            if isempty(app.XaxisTree.SelectedNodes) || ...
                strcmp(app.XaxisTree.SelectedNodes.Text, 'None')
                valid = false;
                app.DigitizeManuallyButton.Tooltip = 'y-axis label must be selected';
                app.AutoDigitizebyColorButton.Tooltip = 'y-axis label must be selected';
            end
            
            if ~(app.MaxYValueEditField.Value > app.MinYValueEditField.Value)
                valid = false;
                app.DigitizeManuallyButton.Tooltip = 'Maximum x-axis limit must be > minimum x-axis limit';
                app.AutoDigitizebyColorButton.Tooltip = 'Maximum x-axis limit must be > minimum x-axis limit';
            end
            if ~(app.MaxXValueEditField.Value > app.MinXValueEditField.Value)
                valid = false;
                app.DigitizeManuallyButton.Tooltip = 'Maximum y-axis limit must be > minimum y-axis limit';
                app.AutoDigitizebyColorButton.Tooltip = 'Maximum y-axis limit must be > minimum y-axis limit';
            end
            
            if isempty(app.curPlotData.img)
                valid = false;
                app.DigitizeManuallyButton.Tooltip = 'no plot selected';
                app.AutoDigitizebyColorButton.Tooltip = 'no plot selected';
            end
            
            if valid
                app.state = app.states.axesValid;
%                 app.DigitizeManuallyButton.Enable = "on";
%                 app.AutoDigitizebyColorButton.Enable = "on";
                
                app.DigitizeManuallyButton.Tooltip = 'Start manual digitizer app';
                app.AutoDigitizebyColorButton.Tooltip = 'Attempt to digitize automatically using trace colors';
            else
%                 app.DigitizeManuallyButton.Enable = "off";
%                 app.AutoDigitizebyColorButton.Enable = "off";               
            end
        end
        
        function resetPlot(app)
            app.MaxYValueEditField.Value = 0; app.MaxYValueEditField.FontColor = [0 0 0];
            app.MinYValueEditField.Value = 0; app.MinYValueEditField.FontColor = [0 0 0];
            app.MaxXValueEditField.Value = 0; app.MaxXValueEditField.FontColor = [0 0 0];
            app.MinXValueEditField.Value = 0; app.MinXValueEditField.FontColor = [0 0 0];
            
            app.YaxisDropDown.Value = '<not selected>'; app.YaxisDropDown.FontColor = [0 0 0];
            app.XaxisDropDown.Value = '<not selected>'; app.XaxisDropDown.FontColor = [0 0 0];
            app.XSIprefixDropDown.Value = ''; app.XSIprefixDropDown.FontColor = [0 0 0];
            app.YSIprefixDropDown.Value = ''; app.YSIprefixDropDown.FontColor = [0 0 0];
        end

        
        function showDigitizedDataTable(app, dP)
            if ~isprop(app.digitizedResultsTable, 'dP')
                addprop(app.digitizedResultsTable, 'dP');
            end
            app.digitizedResultsTable.dP = dP;
                    
            app.digitizedResultsTable.Visible = "on";
            app.digitizedResultsDoneButton.Visible = "on";
            app.digitizedResultsCancelButton.Visible = "on";
            app.FindSimilarPlotsButton.Visible = "off";
            app.digitizedResultsTable.Data = [];
            
            app.TestConditionsPanel.Visible = "on";
            
            Data = [];
                        
            for i = 1:length(dP.plotData)
                app.digitizedResultsTable.ColumnName{2*i} = dP.dataLabels{i};
                app.digitizedResultsTable.ColumnName{2*i-1} = 'X';
                
                curLength = size(Data,1);
                nVals = size(dP.plotData{i},1);
                Data(1:nVals, 2*i-1:2*i) = dP.plotData{i};
                if i > 1
                    if nVals > curLength
                        Data(curLength+1:nVals,1:2*(i-1)) = NaN;
                    elseif curLength > nVals
                        Data(nVals+1:curLength, 2*i-1:2*i) = NaN;
                    end
                end
            end 
            app.digitizedResultsTable.Data = Data;
            app.digitizedResultsTable.ColumnEditable = ones(1, 2*length(dP.plotData))==1;
        end
        
        
        function setupTreeValues(app)
            
%             types = {'None', 'Capacitance', 'Voltage', 'Current', 'Resistance', 'Energy', 'Charge', 'Temperature', 'Thermal Resistance', 'Time', 'Transconductance'};
% %             firstLetters = {'','C','V','I','R','E','Q','T','R','T','G'};
%             units = {'','F','V','A','Ohm','J','C',[char(176) 'C'],[char(176) 'C/W'], 's', 'A/V'};
            t = app.componentType; %transistor();
            sigTypes = t.signalTypes;
            sigUnits = t.signalUnits;
            
            headerY = uitreenode(app.YaxisTree);
            headerY.Text = 'None';
            
            headerX = uitreenode(app.XaxisTree);
            headerX.Text = 'None'; 
            
            for i = 1:length(sigUnits)
                ind = find(strcmp(t.defaultUnits,sigUnits{i}));
                
                if ~isempty(ind)
                    headerY = uitreenode(app.YaxisTree);
                    headerY.Text = sigTypes{i};
                    
                    headerX = uitreenode(app.XaxisTree);
                    headerX.Text = sigTypes{i};
                    
                    headerSig = uitreenode(app.ConditionSignalTree);
                    headerSig.Text = sigTypes{i};
                    
                    for j = 1:length(ind)
                        valY = uitreenode(headerY);
                        valY.Text = [t.knownParams{ind(j)} ', ' t.paramNames{ind(j)}];
                        
                        valX = uitreenode(headerX);
                        valX.Text = [t.knownParams{ind(j)} ', ' t.paramNames{ind(j)}];
                        
                        valSig = uitreenode(headerSig);
                        valSig.Text = [t.knownParams{ind(j)} ', ' t.paramNames{ind(j)}];
                    end
                end
            end
        end


        function labelCurves(app, forceTestCond)
            if nargin == 1
                forceTestCond = 0;
            end
            
            if forceTestCond
                sigLabelsNeeded = 0;
            else
                sigLabelsNeeded = ~isempty(intersect(app.YaxisTree.SelectedNodes, app.YaxisTree.Children));
            end
            
            if length(app.curPlotData.dataLabels) > 1 && ~sigLabelsNeeded
                app.TraceDropDown.Items = [{'all'}; app.curPlotData.dataLabels];
                app.TraceDropDown.Enable = "on";
            elseif length(app.curPlotData.dataLabels) == 1
                app.TraceDropDown.Items = {'all'};
                app.TraceDropDown.Enable = sigLabelsNeeded;
            else
                app.TraceDropDown.Items = app.curPlotData.dataLabels;
                app.TraceDropDown.Enable = sigLabelsNeeded;
            end
            
            app.ConditionEqualsSign.Visible = ~sigLabelsNeeded;
            app.ConditionValueEditField.Visible = ~sigLabelsNeeded;
            app.ConditionSIPrefixDropDown.Visible = ~sigLabelsNeeded;
            app.ConditionUnitEditField.Visible = ~sigLabelsNeeded;
            if sigLabelsNeeded
                %% Need to label the actual signals
                app.TestConditionsPanel.Title = 'Label Traces';
                sigType = find(strcmp({app.ConditionSignalTree.Children.Text},app.YaxisTree.SelectedNodes.Text));

                app.ConditionSignalTree.Children(sigType).expand();
                app.ConditionSignalTree.scroll(app.ConditionSignalTree.Children(sigType));
            else
                app.TestConditionsPanel.Title = 'Test Conditions';
                app.assignTestConditions();
            end
                    
        end
        
        function assignTestConditions(app)
            app.state = app.states.testConditions;
        end
        
        function saveCurrentPlot(app)
            if isempty(app.savedPlotData)
                app.savedPlotData = app.curPlotData;
            else
                app.savedPlotData =[app.savedPlotData, app.curPlotData];
            end
            
%             xind = min([length(app.curPlotData.axisLabels{1}), strfind(app.curPlotData.axisLabels{1}, ',')-1]);
%             yind = min([length(app.curPlotData.axisLabels{2}), strfind(app.curPlotData.axisLabels{2}, ',')-1]);
%             plotName = [app.curPlotData.axisLabels{2}(1:yind) '-vs-' app.curPlotData.axisLabels{1}(1:xind) ];
            plotName = app.curPlotData.getPlotTitle('short');
            
            if isempty(app.digiTracesTable.Data)
                app.digiTracesTable.Data = {
                    plotName, ...
                    num2str(length(app.curPlotData.plotData)), ...
                    strjoin(app.curPlotData.dataLabels, ', '), ...
                    strjoin(app.curPlotData.getTestConditionStrings(), ', ')};
            else
                app.digiTracesTable.Data = [app.digiTracesTable.Data; ...
                    {plotName}, ...
                    {num2str(length(app.curPlotData.plotData))}, ...
                    {strjoin(app.curPlotData.dataLabels, ', ')}, ...
                    {strjoin(app.curPlotData.getTestConditionStrings(), ', ')}];
            end
        end
        
        function loadPartDatabase(app)
            delete(app.DBpartsTree.Children);
            app.DBpartsTree.Children = [];
            for i = 1:length(app.partDB.components)
                part = app.partDB.components(i);
                partNode = uitreenode(app.DBpartsTree);
                partNode.Text = part.partNumber;
                for j = 1:length(part.graphs)
                    graph = uitreenode(partNode);
%                     graph.Text = [part.graphs(j).axisLabels{2} '-vs-' part.graphs(j).axisLabels{1}];
                    graph.Text = part.graphs(j).title;
                end
            end
        end
        
        function setupUnitDropDowns(app)
            vals = app.componentType.SIprefixes.values;
            keys = app.componentType.SIprefixes.keys;
            [~,order] = sort([vals{:}]);
            app.XSIprefixDropDown.Items = {keys{order}};
            app.YSIprefixDropDown.Items = {keys{order}};
            app.ConditionSIPrefixDropDown.Items = {keys{order}};
        end
        
        function resizeToAspectRatio(app, fig, img, originalSize)
            img_ar = size(img,1)/size(img,2);
            fig_ar = originalSize(4)/originalSize(3);
            if abs(img_ar - fig_ar) > 1/originalSize(3)
                %% Aspect ratio is off -- correct it
                if img_ar > fig_ar %img is too tall -- reduce width
                    fig.Position(4) = originalSize(4);
                    fig.Position(3) = round(originalSize(4)/img_ar);
                else
                    fig.Position(3) = originalSize(3);
                    fig.Position(4) = round(originalSize(3)*img_ar);
                end
            end
        end
    end
    
    methods (Access = public)

        
        function grabitComplete(app, handle)
%             app.showGrabitData(handle);
             
            %% Load data
            digiPlot = app.curPlotData;
            digiPlot.loadGrabitData(handle);
            
            app.state = app.states.dataReview;
            
            %% Plot Digitized Data over figure
            cla(app.plotAxes); app.digiTracesTable.Data = [];
            app.plotAxes.Position = app.plotImage.Position;

            digiPlot.plotDigitizedData(app.plotAxes)
            
            app.showDigitizedDataTable(digiPlot);


        end
        
        function reinitialize(app)
%             app.createComponents();
            app.initialize(app.datasheet);
        end
        
        
        function pdfSelectionRectMoved(app, rect)
            if ~isempty(app.otherPlotBounds)
                dist = app.otherPlotBounds- repmat(rect.Position,size(app.otherPlotBounds,1),1);
                dist = dist(:,1).^2 + dist(:,2).^2;
                rect.Position = app.otherPlotBounds(dist == min(dist),:);
            end
            img = app.pdfOrigImage;
            plotImg = img(round(rect.Position(2)):round(sum(rect.Position([2 4]))), ...
                round(rect.Position(1)):round(sum(rect.Position([1 3]))), :);
%             app.plotLoc = rect.Position;
            app.curPlotData = digitizedPlot(app, plotImg, rect.Position, app.pdfIndex);
            
%             app.plotOrigImage = app.curPlotData.img;
            app.plotAxes.Position(4) = 0;
            app.plotMask = 0;
            app.showPlot();


           app.state = app.states.findAxes;

%             app.identifyPlot();
%             app.FindSimilarPlotsButton.Enable = "on"; 
        end


        function results = pdfPage(app)
            results = app.pdfIndex;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialize(app, datasheet, tDB)
            app.UnusedComponentsPanel.Visible = "off";
            movegui(app.DatasheetDigitizerUIFigure,"center");

            eloc = mfilename('fullpath'); %executing location
            divs = strfind(eloc, '\');
            eloc = eloc(1:divs(end));
            cd(eloc)
            tempDir = fullfile(userpath, 'SMPSToolbox', 'PlotDigitizer', 'temp');
            settingsDir = fullfile(userpath, 'SMPSToolbox', 'PlotDigitizer', 'Settings');
            if ~isfolder(tempDir)
                mkdir(tempDir);
            end
            if ~isfolder(settingsDir)
                mkdir(settingsDir);
            end
            addpath(tempDir);
            addpath(settingsDir);
            addpath("src");

            % [flist, plist] = matlab.codetools.requiredFilesAndProducts('plotDigitizer.mlapp') 
            
            %% Make plotAxes invisible
            app.plotAxes.Position(4) = 0;
            

            app.state = app.states.initialize;
            
            if(nargin < 2)
                % No file given
                app.dialogWait = 1;
                datasheetDialog(app);
                while (app.dialogWait == 1)
                    pause(1)
                end
            else
                app.datasheet = datasheet;
            end
            
%             if nargin < 3
                app.partDB = smps.databases.transistorDB();
%             else
%                 app.partDB = tDB;
%             end
            
            app.loadPartDatabase();

            settingsLoc = fullfile(userpath, 'SMPSToolbox', 'PlotDigitizer', 'Settings', 'digitizerSetup.mat');
            
            if ~exist(settingsLoc,'file')
                PDpreferences(app);
                while(~exist(settingsLoc,'file'))
                    pause(1);
                end
                load(settingsLoc, 'PDsettings');
            else
                load(settingsLoc, 'PDsettings');
            end
            
            assert(exist("PDsettings","var")==1, 'settings file not available');       
            
            app.settings = PDsettings;    %store settings loaded from mat file

            assert(isfield(app.settings, 'gsloc'), 'ghostscript location not given');
            assert(isfile(app.settings.gsloc), 'ghoscript file is incorrect')
            
            if ~isfield(app.settings, 'clargs')
                app.settings.clargs = '-sDEVICE=pngalpha -r300 -o';
            end
            
            if ~isfield(app.settings, 'useCVToolbox')
                app.settings.useCVToolbox = 0;
            end
            
            if ~isfield(app.settings, 'useImageToolbox')
                app.settings.useImageToolbox = 0;
            end
            
            %% Check MATLAB version and installed Toolboxes
%             hasImageToolbox = license('test','image_toolbox');
%             hasComputerVision = license('test','Video_and_Image_Blockset');
            v = ver;
            hasImageToolbox = license('test','image_toolbox') && ~isempty(intersect({v.Name}, 'Image Processing Toolbox'));
            hasComputerVision = license('test','Video_and_Image_Blockset') && ~isempty(intersect({v.Name}, 'Computer Vision Toolbox'));
           
            MLver = version;
            [si, ei] = regexp(MLver, '\(.*\)');
            MLversion = {str2double(MLver(si+2:ei-2)), MLver(ei-1)};
            
            canUseRectSelection = (MLversion{1} > 2019 || ...
                ((MLversion{1} == 2019) && MLversion{2} == 'b')) ...
                && hasImageToolbox;
            
           if ~canUseRectSelection
               app.settings.useRectSelection = 0;
           end
           
           if ~hasImageToolbox
               app.settings.useImageToolbox = 0;
           end
           
           if ~hasComputerVision
               app.settings.useCVToolbox = 0;
           end
            
            assert(prod(isfield(app.settings, {'gsloc', 'clargs', ...
                'useImageToolbox', 'useCVToolbox'}))==1, ...
                'Improperly formatted settings file');
            
            PDsettings = app.settings;
            save("settings\digitizerSetup.mat", 'PDsettings');
            
            %% Store element sizes for later reference
            app.pdfOrigPosition = app.pdfAxes.Position;
            app.plotOrigPosition = app.plotImage.Position;
            
            %% Alter items shown based on settings
            app.pdfAxes.Visible = app.settings.useRectSelection;
            app.pdfImage.Visible = ~app.settings.useRectSelection;
            
            if app.settings.useRectSelection == 1
                app.pdfAxes.Visible = "on";
                app.pdfAxes.Position = app.pdfOrigPosition;
                app.pdfImage.Visible = "off";
                app.pdfImage.Position = [app.pdfOrigPosition(1:2) 0 0];
            else
                app.pdfAxes.Visible = "off";
                app.pdfAxes.Position = [app.pdfOrigPosition(1:2) 0 0];
                app.pdfImage.Visible = "on";
                app.pdfImage.Position = app.pdfOrigPosition;
            end
            

            app.digiTracesTable.Data = [];
            app.digitizedResultsDoneButton.Visible = "off";
            app.digitizedResultsCancelButton.Visible = "off";
            app.TestConditionsPanel.Visible = "off";
            
            app.setupTreeValues();
            app.setupUnitDropDowns();

            if app.debug == 0
%                 app.stateEditField.Visible = "off";
                app.stateEditField.Editable = "off";  
%                 app.stateEditFieldLabel.Visible = "off";
                app.loadDatasheetPDF();
            else
                app.findPDFfiles();
                app.pdfIndex = 3;
                app.pdfOrigImage = imread(app.pdfFiles{app.pdfIndex});
                app.showDatasheetPage();
                
                if app.settings.useRectSelection == 1
                    app.pdfAxes.Children(1).Position = 1000*[1.2874    1.1885    1.1550    0.9479];
                    app.pdfSelectionRectMoved(app.pdfAxes.Children(1));
                else
                    app.pdfClicks = [0.5212,    0.6573;    0.9556,    0.3763];
                    app.plotSelectionFromPDF();
                    app.pdfClicks = [];
                end
            end
        end

        % Button pushed function: prevButton
        function prevButtonPushed(app, event)
            if(isempty(app.pdfFiles))
                app.findPDFfiles();
            end
            app.pdfIndex = app.pdfIndex - 1;
            if(app.pdfIndex <= 0)
                app.pdfIndex = length(app.pdfFiles);
            end
            app.pdfOrigImage = imread(app.pdfFiles{app.pdfIndex});
            app.pdfMask = 0;
            app.otherPlotBounds = [];
            app.showDatasheetPage();
        end

        % Image clicked function: pdfImage
        function pdfClick(app, event)
           clickLoc = get(0, 'PointerLocation'); % Origin is bottom-left
           windowLoc = app.DatasheetDigitizerUIFigure.Position; %bottom-left corner of figure from bottom-left corner of screen
           figureLoc = app.pdfImage.Position;  %bottom-left from bottom-left
           
           clickOnFigure = clickLoc - windowLoc(1:2);
           clickOnImage = clickOnFigure - figureLoc(1:2);
           
%            app.clickTimer = tic;
           if isempty(app.otherPlotBounds)
               app.pdfClicks = sortrows([app.pdfClicks; clickOnImage./figureLoc(3:4)]);
               imgDim = [size(app.pdfOrigImage, 2), size(app.pdfOrigImage, 1)];
               
               %% add location indicator
               R = cat(3,255, 0, 0);
               if size(app.pdfClicks,1) == 1
                   mask = app.pdfOrigImage;
                   r = round(size(app.pdfOrigImage,2)/50);
                   
                   loc = round(app.pdfClicks(1,:).*imgDim);
                   loc(:,2) = imgDim(2) - loc(:,2);
                   
                   xr = loc(1)-r:loc(1)+r;
                   xr(xr<1) = [];   xr(xr>size(app.pdfOrigImage,2)) = [];
                   mask(loc(2)-2:loc(2)+2, xr, :) = repmat(R,5, length(xr));
                   
                   yr = loc(2)-r:loc(2)+r;
                   yr(yr<1) = [];   yr(yr>size(app.pdfOrigImage,1)) = [];
                   mask(yr, loc(1)-2:loc(1)+2, :) = repmat(R, length(yr), 5);
                   try %% Occasional "Array dimesntions must match for binary op -- ?
                        app.pdfMask = double(mask) - double(app.pdfOrigImage);
                   catch
                       x=1;
                   end
                   app.showDatasheetPage();
               else
                   locs = round( app.pdfClicks.*repmat(imgDim,2,1) );
                   locs(:,2) = imgDim(2) - locs(:,2);
                   mask = app.pdfOrigImage;
                   rows = sort(locs(:,2));
                   cols = sort(locs(:,1));
                   mask(rows(1)-2:rows(1)+2,cols(1):cols(2),:) = repmat(R,5, cols(2)-cols(1)+1);
                   mask(rows(2)-2:rows(2)+2,cols(1):cols(2),:) = repmat(R,5, cols(2)-cols(1)+1);
                   
                   mask(rows(1):rows(2),cols(1)-2:cols(1)+2,:) = repmat(R,rows(2)-rows(1)+1,5);
                   mask(rows(1):rows(2),cols(2)-2:cols(2)+2,:) = repmat(R,rows(2)-rows(1)+1,5);
    %                mask(locs(1,2)-2:locs(1,2)+2,locs(1,1):locs(2,1),:) = repmat(R,5, locs(2,1)-locs(1,1)+1);
    %                mask(locs(2,2)-2:locs(2,2)+2,locs(1,1):locs(2,1),:) = repmat(R,5, locs(2,1)-locs(1,1)+1);
    %                
    %                mask(locs(1,2):locs(2,2),locs(1,1)-2:locs(1,1)+2,:) = repmat(R,locs(2,2)-locs(1,2)+1,5);
    %                mask(locs(1,2):locs(2,2),locs(2,1)-2:locs(2,1)+2,:) = repmat(R,locs(2,2)-locs(1,2)+1,5);
              
                   app.pdfMask = double(mask) - double(app.pdfOrigImage);
                   app.showDatasheetPage();
               end
           else
               %% Other plots idenfitifed, find which one was clicked
               pixelClick = [round(size(app.pdfOrigImage,2)*(clickOnImage(1)/figureLoc(3))), ...
                        round(size(app.pdfOrigImage,1)*(1-clickOnImage(2)/figureLoc(4)))];            

               
               bounds = [app.otherPlotBounds(:,1:2), app.otherPlotBounds(:,1:2) + app.otherPlotBounds(:,3:4)];
               newPlot = find(pixelClick(1) > bounds(:,1) & pixelClick(1) < bounds(:,3) & ...
                   pixelClick(2) > bounds(:,2) & pixelClick(2) < bounds(:,4));
               if ~isempty(newPlot)
%                    locs = [app.otherPlotBounds(newPlot, 1:2);
%                        app.otherPlotBounds(newPlot, 1:2) + app.otherPlotBounds(newPlot, 3:4)];

                   app.pdfClicks = [bounds(newPlot,1:2); bounds(newPlot,3:4)]./ ...
                       repmat([size(app.pdfOrigImage,2), size(app.pdfOrigImage,1)],2,1);
                   app.pdfClicks(:,2) = 1- app.pdfClicks(:,2);
               end
           end
           
           
           if sum(size(app.pdfClicks) == [2,2]) == 2
               app.plotAxes.Position(4) = 0;
               app.plotSelectionFromPDF();
               app.pdfClicks = [];
           end
        end

        % Image clicked function: plotImage
        function plotClick(app, event)
% % %             if isempty(app.otherPlotBounds)
% % %                 return
% % %             else
% % %                 clickLoc = get(0, 'PointerLocation'); % Origin is bottom-left
% % %                 windowLoc = app.DatasheetDigitizerUIFigure.Position; %bottom-left corner of figure from bottom-left corner of screen
% % %                 figureLoc = app.pdfAxes.Position;  %bottom-left from bottom-left
% % %                 
% % %                 clickOnFigure = clickLoc - windowLoc(1:2);
% % %                 clickOnImage = clickOnFigure - figureLoc(1:2);
% % %                 
% % %                 %% Other plots idenfitifed, find which one was clicked
% % %                 pixelClick = [round(size(app.pdfOrigImage,2)*(clickOnImage(1)/figureLoc(3))), ...
% % %                         round(size(app.pdfOrigImage,1)*(1-clickOnImage(2)/figureLoc(4)))];            
% % %                 
% % %                 
% % %                 bounds = [app.otherPlotBounds(:,1:2), app.otherPlotBounds(:,1:2) + app.otherPlotBounds(:,3:4)];
% % %                 newPlot = find(pixelClick(1) > bounds(:,1) & pixelClick(1) < bounds(:,3) & ...
% % %                    pixelClick(2) > bounds(:,2) & pixelClick(2) < bounds(:,4));
% % %                 if ~isempty(newPlot)
% % %                    pdfSelectionRectMoved(app, bounds(newPlot,:))
% % %                 end
% % %             end
        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            if(isempty(app.pdfFiles))
                app.findPDFfiles();
            end
            app.pdfIndex = app.pdfIndex + 1;
            if(app.pdfIndex > length(app.pdfFiles))
                app.pdfIndex = 1;
            end
            app.pdfOrigImage = imread(app.pdfFiles{app.pdfIndex});
            app.pdfMask = 0;
            app.otherPlotBounds = [];
            app.showDatasheetPage();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.DatasheetDigitizerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1026, 1026};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {399, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: DigitizeManuallyButton
        function startDigitizer(app, event)
            if ~isempty(app.curPlotData.img)
                imwrite(app.curPlotData.img, 'temp/tempPlot.png');
                app.state = app.states.manuallyDigitizing;
                grabit([pwd '\' 'temp\tempPlot.png'], app);
            end
        end

        % Menu selected function: PreferencesMenu
        function filePreferences(app, event)
            app.dialogWait = 1;
            PDpreferences(app);
            while app.dialogWait == 1
                pause(1)
            end         
        end

        % Menu selected function: NewMenu
        function fileNew(app, event)
            app.dialogWait = 1;
            datasheetDialog(app);
            while app.dialogWait == 1
                pause(1)
            end
%             app.loadDatasheetPDF();
            initialize(app, app.datasheet, app.partDB)
        end

        % Value changed function: MaxXValueEditField, MaxYValueEditField, 
        % ...and 2 other components
        function plotFieldEdited(app, event)
            event.Source.FontColor = [0 0 0];
            
            app.checkValidForDigitizing();
        end

        % Button pushed function: AutoDigitizebyColorButton
        function digitizeColoredPlot(app, event)
            if app.settings.useImageToolbox
            try
                app.state = app.states.autoDigitizing;
                digiPlot = app.curPlotData;
                
                % Use user-supplied # of colors if available, otherwise guess
                if app.NTracesEditField.Value == 0
                    nColors = digiPlot.imgTraceColors();
                    app.NTracesEditField.Value = nColors-1;
                    app.NTracesEditField.FontColor = [1 0 0];
                else
                    nColors = app.NTracesEditField.Value+1;
                    app.NTracesEditField.FontColor = [0 0 0];
                end
                
                % Find traces by kmeans clustering
                digiPlot.colorClustering(nColors);
                
                % plot Data and show Data table
                if ~isempty(digiPlot.plotData)
                    cla(app.plotAxes); 
                    app.plotAxes.Position = app.plotImage.Position;
                    digiPlot.plotDigitizedData(app.plotAxes);
                    app.showDigitizedDataTable(digiPlot);
                    app.state = app.states.dataReview;
                end
                
            catch ME
                warning("Automatic plot digitization based on color failed");
                if app.debug == 1
                    getReport(ME)
                end
            end
            end
        end

        % Button pushed function: digitizedResultsCancelButton, 
        % ...and 1 other component
        function digitizedResultsEditingDone(app, event)
%             app.digitizedResultsTable.Visible = "off";
%             app.digitizedResultsDoneButton.Visible = "off";
%             app.digitizedResultsCancelButton.Visible = "off";
%             app.FindSimilarPlotsButton.Visible = "on";

            
            app.NTracesEditField.Value = 0;
            
%             app.TestConditionsPanel.Visible = "off";
            
            if event.Source == app.digitizedResultsDoneButton
                if app.state == app.states.dataReview
                    app.state = app.states.curveLabeling;
                    app.labelCurves();
                elseif app.state == app.states.curveLabeling
                    app.state = app.states.testConditions;
                    app.labelCurves(1);
                elseif app.state == app.states.testConditions
                    app.saveCurrentPlot();
                    app.state = app.states.plotSelection;
                end
            elseif event.Source == app.digitizedResultsCancelButton
                app.state = app.states.axesValid;
                delete(app.displayPoints);
                app.curPlotData.plotData = [];
            end
            
        end

        % Cell selection callback: digitizedResultsTable
        function showTableCellOnPlot(app, event)
            delete(app.displayPoints);
            indices = event.Indices;
            
            dP = app.digitizedResultsTable.dP;
            
            % Ignore any values selected from the x-columns
            xo = mod(indices(:,2),2) == 1;
            indices(xo,:) = [];
            traces = unique(indices(:,2))/2;
            
            if ~isempty(traces)
                % Find corresponding img location for each datapoint
                for i = traces'
                    ind = indices(indices(:,2) == 2*i,:);
                    ind = ind(:,1);
                    xPlotData = app.digitizedResultsTable.Data(ind,i*2-1);
                    
                    [~,locs,~] = intersect(dP.plotData{i}(:,1), xPlotData);
                    ximgData = dP.imgData{i}(locs,1);
                    yimgData = dP.imgData{i}(locs,2);
                    
                    refTrace = findobj(app.plotAxes.Children,'Type','line');
                    traceColor = refTrace(end-i+1).Color;
                    app.displayPoints = plot(app.plotAxes, ximgData , yimgData, 'o', 'LineWidth', 3, 'MarkerEdgeColor', traceColor);
                end
            end

        end

        % Cell edit callback: digitizedResultsTable
        function updateDigizedDataFromTable(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            dP = app.digitizedResultsTable.dP;
            xy = 2- mod(indices(:,2),2);
            
            for i = 1:size(indices,1)
                ind = indices(i,:);
                trace = ceil(ind(2)/2);
                dP.plotData{trace}(ind(1), xy) = newData(i);
                
%                 refTrace = findobj(app.plotAxes.Children,'Type','line');
            end
            dP.convert(2);
            cla(app.plotAxes);
            dP.plotDigitizedData(app.plotAxes);
            
            app.showTableCellOnPlot(event)
        end

        % Button pushed function: FindSimilarPlotsButton
        function findOtherPlots(app, event)
            bboxes = app.curPlotData.findSimilarPlots(app.pdfOrigImage);
            app.otherPlotBounds = bboxes;
            
            labels = cellstr(num2str([1:size(bboxes,1)]'))';
            
            if ~isempty(bboxes)
                mask = insertObjectAnnotation(app.pdfOrigImage, 'rectangle', ...
                            bboxes, labels, ...
                            'Color', 'blue');
                app.pdfMask = double(mask) - double(app.pdfOrigImage);
                app.showDatasheetPage();

            
                if app.settings.useRectSelection == 1
    
                    for i = length(app.pdfAxes.Children):-1:1
                        if strcmp(class(app.pdfAxes.Children(i)), 'images.roi.Rectangle')
                            app.pdfAxes.Children(i).InteractionsAllowed = 'translate';
                        end
                    end
                    
                    roi = drawpoint(app.pdfAxes, 'Position', [100 100]);
                    
    
    %                 updateNextPlot(x,y);
                end
           else
                app.InstructionsTextArea.Value = 'No Other Plots Found';
            end
            
        end

        % Button pushed function: SaveButton
        function exitButton(app, event)
            
            
            outData.file = app.datasheet;
            outData.plotData = app.savedPlotData;

            if isempty(app.PartNumberEditField.Value)
                app.InstructionsTextArea.Value = "Part Number is missing";
                return
            end
            
            sameTransistor = strcmp({app.partDB(:).partNumber},app.PartNumberEditField.Value);
            if any(sameTransistor)
                t = app.partDB(find(sameTransistor,1));
            else
                t = smps.components.transistor;
            end
            t.partNumber = app.PartNumberEditField.Value;
            
            for i=1:length(app.savedPlotData)
                dP = app.savedPlotData(i);
                graph = componentPlotData(smps.components.transistor, dP);
                t.addGraph(graph);
            end
            
            app.partDB.add(t);
            app.partDB.saveDB();
            app.loadPartDatabase();
            
%             outData.partNo = app.PartNumberEditField.Value;
%             
%             outDataName = [outData.partNo '_datasheet'];
%             if exist(outDataName, 'var') == 1
%                 app.InstructionsTextArea.Value = ['variable name ' outDataName 'already exists in the workspace' ];
%                 return
%             end
%             
%             for i = 1:length(outData.plotData)
%                 outData.plotData(i).callingApp = [];
%                 if app.settings.useImageToolbox == 1
%                     outData.plotData(i).img = imresize(rgb2gray(outData.plotData(i).img),[250, 250]);
%                 else
%                     outData.plotData(i).img = [];
%                 end
%             end
%             
%             assignin('base',outDataName,outData);
%             delete(app);
        end

        % Value changed function: ShowPlotMasksButton
        function showPlotMaskToggle(app, event)
%             value = app.ShowPlotMasksButton.Value;
%             if value
%                 app.
%             end
            app.showPlot();
        end

        % Selection changed function: ConditionSignalTree
        function testConditionSignalSelected(app, event)
            t = smps.components.transistor;
            
%             selectedNodes = app.ConditionSignalTree.SelectedNodes;


            if ~isempty(intersect(app.ConditionSignalTree.SelectedNodes, app.ConditionSignalTree.Children))
                return
            end
            
            ind = strfind(app.ConditionSignalTree.SelectedNodes.Text, ',');
            param = app.ConditionSignalTree.SelectedNodes.Text(1:ind-1);
            unit = t.defaultUnits(strcmp(param, t.knownParams));
            mult = t.defaultMultipliers(strcmp(param, t.knownParams));
%             if strcmp(mult, '1')
%                 mult = '';
%             end
            app.ConditionSIPrefixDropDown.Value = mult;
            app.ConditionUnitEditField.Value = unit{:};
            
        end

        % Selection changed function: XaxisTree
        function xAxisSignalSelected(app, event)
%             selectedNodes = app.XaxisTree.SelectedNodes;
            app.checkValidForDigitizing();
        end

        % Button pushed function: AddButton
        function addTraceLabel(app, event)
            ind = strfind(app.ConditionSignalTree.SelectedNodes.Text, ',');
            param = app.ConditionSignalTree.SelectedNodes.Text(1:ind-1);
                
            if app.state == app.states.curveLabeling
                app.plotAxes.Legend.String(strcmp(app.plotAxes.Legend.String, app.TraceDropDown.Value)) = {param};
                app.curPlotData.dataLabels(strcmp(app.curPlotData.dataLabels, app.TraceDropDown.Value)) = {param};
                app.TraceDropDown.Items(strcmp(app.TraceDropDown.Items, app.TraceDropDown.Value)) = {param};
                % Advance cursor to next item, if it exists
                app.TraceDropDown.Value = app.TraceDropDown.Items(min(find(strcmp(app.TraceDropDown.Items, {param}),1)+1,length(app.TraceDropDown.Items)));
                
            elseif app.state == app.states.testConditions
                mult = app.ConditionSIPrefixDropDown.Value;
%                 if strcmp(mult, 'none')
%                     mult = '';
%                 end
                condString = [param, '=', num2str(app.ConditionValueEditField.Value), mult, app.ConditionUnitEditField.Value ];

                if ~strcmp(app.TraceDropDown.Value, 'all')
                    app.curPlotData.dataLabels(strcmp(app.curPlotData.dataLabels, app.TraceDropDown.Value)) = {condString};
                    app.plotAxes.Legend.String(strcmp(app.plotAxes.Legend.String, app.TraceDropDown.Value)) = {condString};
                    app.TraceDropDown.Items(strcmp(app.TraceDropDown.Items, app.TraceDropDown.Value)) = {condString};  
                    % Advance cursor to next item, if it exists
                    app.TraceDropDown.Value = app.TraceDropDown.Items(min(find(strcmp(app.TraceDropDown.Items, {condString}),1)+1,length(app.TraceDropDown.Items)));
                else
                    app.curPlotData.addTestCondition(app.TraceDropDown.Value, param, ...
                        app.ConditionValueEditField.Value, app.ConditionSIPrefixDropDown.Value, app.ConditionUnitEditField.Value);
                end
            end
        end

        % Key press function: DatasheetDigitizerUIFigure
        function keyPressed(app, event)
            if strcmp(event.Key, 'delete')
                if app.state == app.states.dataReview
                    
                end
            end
            
        end

        % Button pushed function: AutoDigitizeBWButton
        function digitizeBWplot(app, event)
            if app.settings.useImageToolbox
            try
                app.state = app.states.autoDigitizing;
                digiPlot = app.curPlotData;


                % Use user-supplied # of colors if available, otherwise guess
                if app.NTracesEditField.Value == 0
                    [nTraces, L] = digiPlot.BWimgTraceNumber();
                    app.NTracesEditField.Value = nTraces;
                    app.NTracesEditField.FontColor = [1 0 0];
                    digiPlot.BWlabel(nTraces, L);
                else
                    nTraces = app.NTracesEditField.Value;
                    app.NTracesEditField.FontColor = [0 0 0];
                    digiPlot.BWlabel(nTraces);
                end
%                 
                % Find traces by group labeling
                digiPlot.BWlabel(nTraces);
%                 
                % plot Data and show Data table
                if ~isempty(digiPlot.plotData)
                    cla(app.plotAxes); 
                    app.plotAxes.Position = app.plotImage.Position;
                    digiPlot.plotDigitizedData(app.plotAxes);
                    app.showDigitizedDataTable(digiPlot);
                    app.state = app.states.dataReview;
                else
                    app.state = app.states.axesValid;
                    app.InstructionsTextArea.Value = "Auto-digitization of greyscale plot failed";
                end
                
            catch ME
                warning("Automatic plot digitization of black & white plot failed");
                if app.debug == 1
                    getReport(ME)
                end
            end
            end
        end

        % Value changed function: PartNumberEditField
        function partNumberEdited(app, event)
            partNo = app.PartNumberEditField.Value;
            if any(strcmp(partNo, {app.DBpartsTree.Children.Text}))
                app.DBpartsTree.collapse('all');
                ind = find(strcmp(partNo, {app.DBpartsTree.Children.Text}));
                app.DBpartsTree.Children(ind).expand();
                app.DBpartsTree.SelectedNodes = app.DBpartsTree.Children(ind);
            else
                app.DBpartsTree.collapse('all');
            end
        end

        % Button pushed function: AutoDigitizeButton
        function autoDigizitze(app, event)
            if ~app.settings.useImageToolbox
                app.InstructionsTextArea.Value = "Auto-digitization requires the Image Toolbox";
                return;
            end
            try
                app.state = app.states.autoDigitizing;
                digiPlot = app.curPlotData;
                
                uncoloredImg = digiPlot.removeUncoloredPixels();
                if length(unique(uncoloredImg)) == 1
                    %% Use B%W autodigitization
                    app.InstructionsTextArea.Value = "Auto-digitizing based on greyscale plot";
                    
                    % Use user-supplied # of traces if available, otherwise guess
                    if app.NTracesEditField.Value == 0
                        [nTraces, L] = digiPlot.BWimgTraceNumber();
                        app.NTracesEditField.Value = nTraces;
                        app.NTracesEditField.FontColor = [1 0 0];
                        digiPlot.BWlabel(nTraces, L);
                    else
                        nTraces = app.NTracesEditField.Value;
                        app.NTracesEditField.FontColor = [0 0 0];
                        digiPlot.BWlabel(nTraces);
                    end
                    
                    % Find traces by group labeling
                    digiPlot.BWlabel(nTraces);
                    
                else
                    %% Use color-based autodigitization
                    app.InstructionsTextArea.Value = "Auto-digitizing based on colored plot";
                    
                    % Use user-supplied # of colors if available, otherwise guess
                    if app.NTracesEditField.Value == 0
                        nColors = digiPlot.imgTraceColors();
                        app.NTracesEditField.Value = nColors-1;
                        app.NTracesEditField.FontColor = [1 0 0];
                    else
                        nColors = app.NTracesEditField.Value+1;
                        app.NTracesEditField.FontColor = [0 0 0];
                    end
                    
                    % Find traces by kmeans clustering
                    digiPlot.colorClustering(nColors);
                    
                end
            
                % plot Data and show Data table
                if ~isempty(digiPlot.plotData)
                    cla(app.plotAxes); 
                    app.plotAxes.Position = app.plotImage.Position;
                    digiPlot.plotDigitizedData(app.plotAxes);
                    app.plotImage.Visible = false;
                    app.showDigitizedDataTable(digiPlot);
                    app.state = app.states.dataReview;
                else
                    app.state = app.states.axesValid;
                    app.InstructionsTextArea.Value = "Auto-digitization of plot failed";
                end
                
            catch ME
                app.InstructionsTextArea.Value = "Automatic plot digitization of black & white plot failed";
                app.InstructionsTextArea.FontColor = [1 0 0];
                if app.debug == 1
                    getReport(ME)
                end
            end
        end

        % Button pushed function: TunePlotLimitsButton
        function showAxesTuning(app, event)
            if strcmp(app.XaxisPlotLimitsPanel.Visible, "off")
                app.XaxisPlotLimitsPanel.Visible = "on";
                app.YaxisPlotLimitsPanel.Visible = "on";
                
                app.XaxisPlotLimitsPanel.Position(1) = app.plotImage.Position(1);
                app.XaxisPlotLimitsPanel.Position(3) = app.plotImage.Position(3);
                app.XaxisPlotLimitsPanel.Position(2) = app.plotImage.Position(2) - app.XaxisPlotLimitsPanel.Position(4);
                
                headerHeight = (app.YaxisPlotLimitsPanel.Position(4) - app.YaxisPlotLimitsPanel.InnerPosition(4));
                app.YaxisPlotLimitsPanel.Position(2) = app.plotImage.Position(2); 
                app.YaxisPlotLimitsPanel.Position(4) = app.plotImage.Position(4) + headerHeight;
                app.YaxisPlotLimitsPanel.Position(1) = app.plotImage.Position(1) - app.YaxisPlotLimitsPanel.Position(3);
                
                pause(0.5)
                
                app.xAxisSlider.Position(1) = 0;
                app.xAxisSlider.Position(3) = app.xAxisSlider.Parent.Position(3);
                
                app.yAxisSlider.Position(2) = 0;
                app.yAxisSlider.Position(4) = app.yAxisSlider.Parent.Position(4) - headerHeight;
                
                app.xAxisSlider.Limits = [0 size(app.plotImage.ImageSource,2)];
                app.XtuningButtonGroupChanged(0);
                
                app.yAxisSlider.Limits = [0 size(app.plotImage.ImageSource,1)];
                app.YtuningButtonGroupChanged(0);
                
                app.TunePlotLimitsButton.Text = "Axis Tuning Complete";
                app.state = app.states.findAxes;
            elseif strcmp(app.XaxisPlotLimitsPanel.Visible, "on")
                app.XaxisPlotLimitsPanel.Visible = "off";
                app.YaxisPlotLimitsPanel.Visible = "off";
                app.TunePlotLimitsButton.Text = "Tune Plot Limits";
                app.state = app.states.axesCalibration;
                
                if(app.settings.useCVToolbox)
                    try
                        maskedImg = app.curPlotData.axesMask();
                        maskedImg = app.curPlotData.OCRaxes(maskedImg);
                        app.plotMask = double(maskedImg) - double(app.curPlotData.img);
                    catch ME
                        warning("OCR failed, attempting to continue.")
                        if app.debug == 1
                            getReport(ME)
                        end
                    end
                end
                app.showPlot();
                app.checkValidForDigitizing();
            end
        end

        % Callback function: xAxisSlider, xAxisSlider
        function XsliderAxesUpdate(app, event)
%             changingValue = event.Value;

            % [colLeft colRight rowBottom rowTop] -- location of plot on image
            if app.XtuningButtonGroup.SelectedObject == app.XminButton
                app.curPlotData.imgAxes.colLeft = round(event.Value);
            elseif app.XtuningButtonGroup.SelectedObject == app.XmaxButton
                app.curPlotData.imgAxes.colRight = round(event.Value);
            end
            
            app.plotMask = double(app.curPlotData.axesMask()) - double(app.curPlotData.img); 
            app.showPlot();
        end

        % Selection changed function: XtuningButtonGroup
        function XtuningButtonGroupChanged(app, event)
            if app.XtuningButtonGroup.SelectedObject == app.XminButton
                app.xAxisSlider.Value = app.curPlotData.imgAxes.colLeft;
            elseif app.XtuningButtonGroup.SelectedObject == app.XmaxButton
                app.xAxisSlider.Value = app.curPlotData.imgAxes.colRight;
            end
            
        end

        % Callback function: yAxisSlider, yAxisSlider
        function YsliderAxesUpdate(app, event)
            % [colLeft colRight rowBottom rowTop] -- location of plot on image
            if app.YtuningButtonGroup.SelectedObject == app.YminButton
                app.curPlotData.imgAxes.rowBottom = round(size(app.plotImage.ImageSource,1)-event.Value);
            elseif app.YtuningButtonGroup.SelectedObject == app.YmaxButton
                app.curPlotData.imgAxes.rowTop = round(size(app.plotImage.ImageSource,1)-event.Value);
            end
            
            app.plotMask = double(app.curPlotData.axesMask()) - double(app.curPlotData.img); 
            app.showPlot();
            
        end

        % Selection changed function: YtuningButtonGroup
        function YtuningButtonGroupChanged(app, event)
            if app.YtuningButtonGroup.SelectedObject == app.YminButton
                app.yAxisSlider.Value = size(app.plotImage.ImageSource,1) - app.curPlotData.imgAxes.rowBottom;
            elseif app.YtuningButtonGroup.SelectedObject == app.YmaxButton
                app.yAxisSlider.Value = size(app.plotImage.ImageSource,1) - app.curPlotData.imgAxes.rowTop;
            end
            
        end

        % Close request function: DatasheetDigitizerUIFigure
        function DatasheetDigitizerUIFigureCloseRequest(app, event)
            selection = uiconfirm(app.DatasheetDigitizerUIFigure, 'Are you sure you want to close?  Any unsaved plots will be lost.', 'Confirm Exit');
            if strcmp(selection, 'OK') == 1
                delete(app);
            end
            
        end

        % Button pushed function: IdentifyPlotButton
        function IdentifyPlotButtonPushed(app, event)
            app.identifyPlot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DatasheetDigitizerUIFigure = uifigure('Visible', 'off');
            app.DatasheetDigitizerUIFigure.Name = 'Datasheet Digitizer';
            app.DatasheetDigitizerUIFigure.CloseRequestFcn = createCallbackFcn(app, @DatasheetDigitizerUIFigureCloseRequest, true);
            app.DatasheetDigitizerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.DatasheetDigitizerUIFigure.KeyPressFcn = createCallbackFcn(app, @keyPressed, true);
            app.FileMenu = uimenu(app.DatasheetDigitizerUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @fileNew, true);
            app.NewMenu.Text = 'New';
            app.PreferencesMenu = uimenu(app.FileMenu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @filePreferences, true);
            app.PreferencesMenu.Text = 'Preferences';
            app.GridLayout = uigridlayout(app.DatasheetDigitizerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.pdfAxes = uiaxes(app.LeftPanel);
            app.pdfAxes.XTick = [];
            app.pdfAxes.YTick = [];
            app.pdfImage = uiimage(app.LeftPanel);
            app.pdfImage.ImageClickedFcn = createCallbackFcn(app, @pdfClick, true);
            app.prevButton = uibutton(app.LeftPanel, 'push');
            app.prevButton.ButtonPushedFcn = createCallbackFcn(app, @prevButtonPushed, true);
            app.prevButton.Text = 'prev';
            app.InstructionsTextAreaLabel = uilabel(app.LeftPanel);
            app.InstructionsTextAreaLabel.Visible = 'off';
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.InstructionsTextArea = uitextarea(app.LeftPanel);
            app.PartNumberEditFieldLabel = uilabel(app.LeftPanel);
            app.PartNumberEditFieldLabel.Text = 'Part Number';
            app.PartNumberEditField = uieditfield(app.LeftPanel, 'text');
            app.PartNumberEditField.ValueChangedFcn = createCallbackFcn(app, @partNumberEdited, true);
            app.nextButton = uibutton(app.LeftPanel, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = 'next';
            app.DatasheetLabel = uilabel(app.LeftPanel);
            app.DatasheetLabel.Text = 'Datasheet';
            app.digitizedResultsTable = uitable(app.LeftPanel);
            app.digitizedResultsTable.ColumnName = {'X'; 'Y1'};
            app.digitizedResultsTable.RowName = {};
            app.digitizedResultsTable.CellEditCallback = createCallbackFcn(app, @updateDigizedDataFromTable, true);
            app.digitizedResultsTable.CellSelectionCallback = createCallbackFcn(app, @showTableCellOnPlot, true);
            app.digitizedResultsTable.Visible = 'off';
            app.digitizedResultsDoneButton = uibutton(app.LeftPanel, 'push');
            app.digitizedResultsDoneButton.ButtonPushedFcn = createCallbackFcn(app, @digitizedResultsEditingDone, true);
            app.digitizedResultsDoneButton.Text = 'Done';
            app.FindSimilarPlotsButton = uibutton(app.LeftPanel, 'push');
            app.FindSimilarPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @findOtherPlots, true);
            app.FindSimilarPlotsButton.Enable = 'off';
            app.FindSimilarPlotsButton.Text = 'Find Similar Plots';
            app.digitizedResultsCancelButton = uibutton(app.LeftPanel, 'push');
            app.digitizedResultsCancelButton.ButtonPushedFcn = createCallbackFcn(app, @digitizedResultsEditingDone, true);
            app.digitizedResultsCancelButton.Text = 'Cancel';
            app.TestConditionsPanel = uipanel(app.LeftPanel);
            app.TestConditionsPanel.Title = 'Test Conditions';
            app.ConditionSignalTree = uitree(app.TestConditionsPanel);
            app.ConditionSignalTree.SelectionChangedFcn = createCallbackFcn(app, @testConditionSignalSelected, true);
            app.ConditionEqualsSign = uieditfield(app.TestConditionsPanel, 'text');
            app.ConditionEqualsSign.Editable = 'off';
            app.ConditionEqualsSign.Value = '=';
            app.ConditionValueEditField = uieditfield(app.TestConditionsPanel, 'numeric');
            app.ConditionSIPrefixDropDown = uidropdown(app.TestConditionsPanel);
            app.ConditionSIPrefixDropDown.Items = {'f', 'p', 'n', 'u', 'm', 'none', 'k', 'M', 'G'};
            app.ConditionSIPrefixDropDown.Value = 'none';
            app.ConditionUnitEditField = uieditfield(app.TestConditionsPanel, 'text');
            app.ConditionUnitEditField.Editable = 'off';
            app.TraceDropDownLabel = uilabel(app.TestConditionsPanel);
            app.TraceDropDownLabel.Text = 'Trace:';
            app.TraceDropDown = uidropdown(app.TestConditionsPanel);
            app.AddButton = uibutton(app.TestConditionsPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @addTraceLabel, true);
            app.AddButton.Text = 'Add';
            app.RightPanel = uipanel(app.GridLayout);
            app.plotAxes = uiaxes(app.RightPanel);
            app.plotAxes.XTick = [];
            app.plotAxes.YTick = [];
            app.DigitizeManuallyButton = uibutton(app.RightPanel, 'push');
            app.DigitizeManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @startDigitizer, true);
            app.DigitizeManuallyButton.Enable = 'off';
            app.DigitizeManuallyButton.Text = 'Digitize Manually';
            app.plotImage = uiimage(app.RightPanel);
            app.plotImage.ImageClickedFcn = createCallbackFcn(app, @plotClick, true);
            app.digiTracesTable = uitable(app.RightPanel);
            app.digiTracesTable.ColumnName = {'Plot'; 'Number of Traces'; 'Curves'; 'Test Conditions'};
            app.digiTracesTable.RowName = {};
            app.digiTracesTable.ColumnEditable = [false false false];
            app.ofTracesLabel = uilabel(app.RightPanel);
            app.ofTracesLabel.Text = '# of Traces';
            app.NTracesEditField = uieditfield(app.RightPanel, 'numeric');
            app.SaveButton = uibutton(app.RightPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @exitButton, true);
            app.SaveButton.Text = 'Save';
            app.ShowPlotMasksButton = uibutton(app.RightPanel, 'state');
            app.ShowPlotMasksButton.ValueChangedFcn = createCallbackFcn(app, @showPlotMaskToggle, true);
            app.ShowPlotMasksButton.Text = 'Show Plot Masks';
            app.ShowPlotMasksButton.Value = true;
            app.stateEditFieldLabel = uilabel(app.RightPanel);
            app.stateEditFieldLabel.Text = 'state';
            app.stateEditField = uieditfield(app.RightPanel, 'numeric');
            app.stateEditField.Editable = 'off';
            app.DBpartsTree = uitree(app.RightPanel);
            app.TransistorDatabaseLabel = uilabel(app.RightPanel);
            app.TransistorDatabaseLabel.Text = 'Transistor Database';
            app.XaxisLabel = uilabel(app.RightPanel);
            app.XaxisLabel.Text = 'X-axis';
            app.AutoDigitizeButton = uibutton(app.RightPanel, 'push');
            app.AutoDigitizeButton.ButtonPushedFcn = createCallbackFcn(app, @autoDigizitze, true);
            app.AutoDigitizeButton.Enable = 'off';
            app.AutoDigitizeButton.Text = 'Auto-Digitize';
            app.UnusedComponentsPanel = uipanel(app.RightPanel);
            app.UnusedComponentsPanel.Title = 'Unused Components';
            app.AutoDigitizebyColorButton = uibutton(app.UnusedComponentsPanel, 'push');
            app.AutoDigitizebyColorButton.ButtonPushedFcn = createCallbackFcn(app, @digitizeColoredPlot, true);
            app.AutoDigitizebyColorButton.Enable = 'off';
            app.AutoDigitizebyColorButton.Text = 'Auto-Digitize by Color';
            app.AutoDigitizeBWButton = uibutton(app.UnusedComponentsPanel, 'push');
            app.AutoDigitizeBWButton.ButtonPushedFcn = createCallbackFcn(app, @digitizeBWplot, true);
            app.AutoDigitizeBWButton.Enable = 'off';
            app.AutoDigitizeBWButton.Text = 'Auto-Digitize B&W';
            app.skewedaxesCheckBox = uicheckbox(app.UnusedComponentsPanel);
            app.skewedaxesCheckBox.Enable = 'off';
            app.skewedaxesCheckBox.Text = 'skewed axes';
            app.XaxisDropDownLabel = uilabel(app.UnusedComponentsPanel);
            app.XaxisDropDownLabel.Enable = 'off';
            app.XaxisDropDownLabel.Visible = 'off';
            app.XaxisDropDownLabel.Text = 'X-axis';
            app.XaxisDropDown = uidropdown(app.UnusedComponentsPanel);
            app.XaxisDropDown.Items = {'<not selected>', 'Capacitance', 'Voltage', 'Current', 'Resistance', 'Energy', 'Charge', 'Temperature', 'Thermal Resistance', 'Time'};
            app.XaxisDropDown.Enable = 'off';
            app.XaxisDropDown.Visible = 'off';
            app.XaxisDropDown.Value = '<not selected>';
            app.YaxisDropDownLabel = uilabel(app.UnusedComponentsPanel);
            app.YaxisDropDownLabel.Enable = 'off';
            app.YaxisDropDownLabel.Visible = 'off';
            app.YaxisDropDownLabel.Text = 'Y-axis';
            app.YaxisDropDown = uidropdown(app.UnusedComponentsPanel);
            app.YaxisDropDown.Items = {'<not selected>', 'Capacitance', 'Voltage', 'Current', 'Resistance', 'Energy', 'Charge', 'Temperature', 'Thermal Resistance', 'Time', ''};
            app.YaxisDropDown.Enable = 'off';
            app.YaxisDropDown.Visible = 'off';
            app.YaxisDropDown.Value = '<not selected>';
            app.XaxisCalibrationPanel = uipanel(app.RightPanel);
            app.XaxisCalibrationPanel.Title = 'X-axis Calibration';
            app.XaxisTree = uitree(app.XaxisCalibrationPanel);
            app.XaxisTree.SelectionChangedFcn = createCallbackFcn(app, @xAxisSignalSelected, true);
            app.NormalizedXaxisCheckBox = uicheckbox(app.XaxisCalibrationPanel);
            app.NormalizedXaxisCheckBox.Text = 'Normalized  X-axis';
            app.LogarithmicXaxisCheckBox = uicheckbox(app.XaxisCalibrationPanel);
            app.LogarithmicXaxisCheckBox.Text = 'Logarithmic X-axis';
            app.SIprefixDropDown_2Label = uilabel(app.XaxisCalibrationPanel);
            app.SIprefixDropDown_2Label.Text = 'SI prefix';
            app.XSIprefixDropDown = uidropdown(app.XaxisCalibrationPanel);
            app.XSIprefixDropDown.Items = {'f', 'p', 'n', 'u', 'm', 'none', 'k', 'M', 'G'};
            app.XSIprefixDropDown.Value = 'none';
            app.MaxXValueEditFieldLabel = uilabel(app.XaxisCalibrationPanel);
            app.MaxXValueEditFieldLabel.Text = 'Max X-Value';
            app.MaxXValueEditField = uieditfield(app.XaxisCalibrationPanel, 'numeric');
            app.MaxXValueEditField.ValueChangedFcn = createCallbackFcn(app, @plotFieldEdited, true);
            app.MinXValueEditFieldLabel = uilabel(app.XaxisCalibrationPanel);
            app.MinXValueEditFieldLabel.Text = 'Min X-Value';
            app.MinXValueEditField = uieditfield(app.XaxisCalibrationPanel, 'numeric');
            app.MinXValueEditField.ValueChangedFcn = createCallbackFcn(app, @plotFieldEdited, true);
            app.YaxisCalibrationPanel = uipanel(app.RightPanel);
            app.YaxisCalibrationPanel.Title = 'Y-axis Calibration';
            app.NormalizedYaxisCheckBox = uicheckbox(app.YaxisCalibrationPanel);
            app.NormalizedYaxisCheckBox.Text = 'Normalized  Y-axis';
            app.LogarithmicYaxisCheckBox = uicheckbox(app.YaxisCalibrationPanel);
            app.LogarithmicYaxisCheckBox.Text = 'Logarithmic Y-axis';
            app.YaxisTree = uitree(app.YaxisCalibrationPanel);
            app.YaxisLabel = uilabel(app.YaxisCalibrationPanel);
            app.YaxisLabel.Text = 'Y-axis';
            app.MaxYValueEditFieldLabel = uilabel(app.YaxisCalibrationPanel);
            app.MaxYValueEditFieldLabel.Text = 'Max Y-Value';
            app.MaxYValueEditField = uieditfield(app.YaxisCalibrationPanel, 'numeric');
            app.MaxYValueEditField.ValueChangedFcn = createCallbackFcn(app, @plotFieldEdited, true);
            app.MinYValueEditFieldLabel = uilabel(app.YaxisCalibrationPanel);
            app.MinYValueEditFieldLabel.Text = 'Min Y-Value';
            app.MinYValueEditField = uieditfield(app.YaxisCalibrationPanel, 'numeric');
            app.MinYValueEditField.ValueChangedFcn = createCallbackFcn(app, @plotFieldEdited, true);
            app.SIprefixDropDown_2Label_2 = uilabel(app.YaxisCalibrationPanel);
            app.SIprefixDropDown_2Label_2.Text = 'SI prefix';
            app.YSIprefixDropDown = uidropdown(app.YaxisCalibrationPanel);
            app.YSIprefixDropDown.Items = {'f', 'p', 'n', 'u', 'm', 'none', 'k', 'M', 'G'};
            app.YSIprefixDropDown.Value = 'none';
            app.XaxisPlotLimitsPanel = uipanel(app.RightPanel);
            app.XaxisPlotLimitsPanel.Title = 'X-axis Plot Limits';
            app.XaxisPlotLimitsPanel.Visible = 'off';
            app.xAxisSlider = uislider(app.XaxisPlotLimitsPanel);
            app.xAxisSlider.ValueChangedFcn = createCallbackFcn(app, @XsliderAxesUpdate, true);
            app.xAxisSlider.ValueChangingFcn = createCallbackFcn(app, @XsliderAxesUpdate, true);
            app.XtuningButtonGroup = uibuttongroup(app.XaxisPlotLimitsPanel);
            app.XtuningButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @XtuningButtonGroupChanged, true);
            app.XminButton = uitogglebutton(app.XtuningButtonGroup);
            app.XminButton.Text = 'Xmin';
            app.XminButton.Value = true;
            app.XmaxButton = uitogglebutton(app.XtuningButtonGroup);
            app.XmaxButton.Text = 'Xmax';
            app.TunePlotLimitsButton = uibutton(app.RightPanel, 'push');
            app.TunePlotLimitsButton.ButtonPushedFcn = createCallbackFcn(app, @showAxesTuning, true);
            app.TunePlotLimitsButton.Text = 'Tune Plot Limits';
            app.YaxisPlotLimitsPanel = uipanel(app.RightPanel);
            app.YaxisPlotLimitsPanel.Title = 'Y-axis Plot Limits';
            app.YaxisPlotLimitsPanel.Visible = 'off';
            app.yAxisSlider = uislider(app.YaxisPlotLimitsPanel);
            app.yAxisSlider.ValueChangedFcn = createCallbackFcn(app, @YsliderAxesUpdate, true);
            app.yAxisSlider.ValueChangingFcn = createCallbackFcn(app, @YsliderAxesUpdate, true);
            app.YtuningButtonGroup = uibuttongroup(app.YaxisPlotLimitsPanel);
            app.YtuningButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @YtuningButtonGroupChanged, true);
            app.YmaxButton = uitogglebutton(app.YtuningButtonGroup);
            app.YmaxButton.Text = 'Ymax';
            app.YmaxButton.Value = true;
            app.YminButton = uitogglebutton(app.YtuningButtonGroup);
            app.YminButton.Text = 'Ymin';
            app.IdentifyPlotButton = uibutton(app.RightPanel, 'push');
            app.IdentifyPlotButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyPlotButtonPushed, true);
            app.IdentifyPlotButton.Text = 'Identify Plot';
            % Show the figure after all components are created
            app.DatasheetDigitizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotDigitizer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DatasheetDigitizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)initialize(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DatasheetDigitizerUIFigure)
        end
    end
end