classdef plotDigitizer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DatasheetDigitizerUIFigure  matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        NewMenu                     matlab.ui.container.Menu
        PreferencesMenu             matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        pdfImage                    matlab.ui.control.Image
        prevButton                  matlab.ui.control.Button
        InstructionsTextAreaLabel   matlab.ui.control.Label
        InstructionsTextArea        matlab.ui.control.TextArea
        PartNumberEditFieldLabel    matlab.ui.control.Label
        PartNumberEditField         matlab.ui.control.EditField
        nextButton                  matlab.ui.control.Button
        DatasheetLabel              matlab.ui.control.Label
        pdfAxes                     matlab.ui.control.UIAxes
        digitizedResultsTable       matlab.ui.control.Table
        digitizedResultsDoneButton  matlab.ui.control.Button
        FindSimilarPlotsButton      matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        plotImage                   matlab.ui.control.Image
        DigitzeManuallyButton       matlab.ui.control.Button
        XaxisDropDownLabel          matlab.ui.control.Label
        XaxisDropDown               matlab.ui.control.DropDown
        YaxisDropDownLabel          matlab.ui.control.Label
        YaxisDropDown               matlab.ui.control.DropDown
        SIprefixDropDown_2Label     matlab.ui.control.Label
        XSIprefixDropDown           matlab.ui.control.DropDown
        NormalizedYaxisCheckBox     matlab.ui.control.CheckBox
        LogarithmicYaxisCheckBox    matlab.ui.control.CheckBox
        NormalizedXaxisCheckBox     matlab.ui.control.CheckBox
        LogarithmicXaxisCheckBox    matlab.ui.control.CheckBox
        MaxYValueEditFieldLabel     matlab.ui.control.Label
        MaxYValueEditField          matlab.ui.control.NumericEditField
        MinYValueEditFieldLabel     matlab.ui.control.Label
        MinYValueEditField          matlab.ui.control.NumericEditField
        MaxXValueEditFieldLabel     matlab.ui.control.Label
        MaxXValueEditField          matlab.ui.control.NumericEditField
        MinXValueEditFieldLabel     matlab.ui.control.Label
        MinXValueEditField          matlab.ui.control.NumericEditField
        skewedaxesCheckBox          matlab.ui.control.CheckBox
        digiTracesTable             matlab.ui.control.Table
        SIprefixDropDown_2Label_2   matlab.ui.control.Label
        YSIprefixDropDown           matlab.ui.control.DropDown
        plotAxes                    matlab.ui.control.UIAxes
        AutoDigitizebyColorButton   matlab.ui.control.Button
        ofTracesLabel               matlab.ui.control.Label
        NTracesEditField            matlab.ui.control.NumericEditField
        DoneButton                  matlab.ui.control.Button
        ShowPlotMasksButton         matlab.ui.control.StateButton
        YaxisTree                   matlab.ui.container.Tree
        XaxisTree                   matlab.ui.container.Tree
        XTreeLabel                  matlab.ui.control.EditField
        YTreeLabel                  matlab.ui.control.EditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        debug = 1
        settings
        
        pdfFiles % cell array of filenames of all png pages of pdf datasheet
        pdfIndex = 1
        
        pdfClicks
%         clickTimer = 0 % timer between two clicks
        
        pdfSelectionRect % Description
        
        pdfOrigImage
        pdfMask = 0
        otherPlotBounds
        
%         showPlotMask = true;
        plotMask = 0
        
        displayPoints % Markers for post-digized table data selection
        
        curPlotData 
        savedPlotData
    end
    
    properties (Access = public)
        dialogWait = 0 % flag to indicate that a dialog window is open
        datasheet 
    end
    
    methods (Access = private)
        
        function findPDFfiles(app)          
            S = dir('temp/datasheet-p*.png');
            app.pdfFiles = {S.name};
            if ~isempty(app.pdfFiles)
                app.pdfIndex = 1;
                app.pdfOrigImage = imread(app.pdfFiles{app.pdfIndex});
                app.showDatasheetPage();
            else
                app.pdfIndex = 0;
            end
        end
        
        function plotSelectionFromPDF(app)
            pageData = app.pdfOrigImage;
            app.plotMask = 0;
            
            cols = sort(round(size(pageData,2)*(app.pdfClicks(:,1))));
            rows = sort(round(size(pageData,1)*(1-app.pdfClicks(:,2))));
 
            imgSelection = pageData(rows(1):rows(2), cols(1):cols(2),:);
            app.curPlotData = digitizedPlot(app, imgSelection, [cols(1), rows(1), diff(cols), diff(rows)], app.pdfIndex);
            
            app.identifyPlot();
            app.FindSimilarPlotsButton.Enable = "on";
            
        end
        
        function loadDatasheetPDF(app)
            
            if ~strcmp(app.datasheet(end-3:end), '.pdf')
                error('Datasheet given must be a direct link to a PDF')
            end
            
            if strcmp(app.datasheet(1:4), 'http')
                % first check if it is already downloaded
                loc = find(app.datasheet == '/', 1, 'last');
                fn = ['temp\' app.datasheet(loc+1:end)];
                if ~isfile(fn)
                    outfilename = websave(fn,app.datasheet);
                    app.datasheet = outfilename;
                else
                    %already downloaded it
                    app.datasheet = fn;
                end
                
            end

            %% Parse PDF into individual page images
            % First delete any old ones
            if(isfile(app.datasheet))
                app.findPDFfiles;
                for i = 1:length(app.pdfFiles)
                    fn = ['temp\' app.pdfFiles{i}];
                    delete(fn);
    %                 display(['temp\' app.pdfFiles{i}])
                end
            end
            
            % Then create pngs of each page
            cmd = ['"' char(app.settings.gsloc) '" ' char(app.settings.clargs) ' temp\datasheet-p%03d.png ' char(app.datasheet)];
            system(cmd);
            
            app.pdfOrigImage = imread('temp\datasheet-p001.png');
            app.showDatasheetPage();

        end
        
        function identifyPlot(app)
            app.curPlotData.loadAppData();
            foundAxes = app.curPlotData.findPlotArea();
    
            if length(foundAxes) == 4
                maskedImg = app.curPlotData.axesMask();
                app.plotMask = double(maskedImg) - double(app.curPlotData.img);

                %% Search for axis max/min
                if(app.settings.useCVToolbox)
                    try
                        maskedImg = app.curPlotData.OCRaxes(maskedImg);
                        app.plotMask = double(maskedImg) - double(app.curPlotData.img);
                    catch ME
                        warning("OCR failed, attempting to continue.")
                        getReport(ME)
                    end
                end
                app.showPlot();
                app.checkValidForDigitizing();
                
            end
        end
        
        function showDatasheetPage(app)

            img = uint8(double(app.pdfOrigImage) + app.pdfMask);
            img_ar = size(img,1)/size(img,2); %h/w
            
            if app.settings.useRectSelection
                fig_ar = app.pdfAxes.Position(4)/app.pdfAxes.Position(3);
                if abs(img_ar - fig_ar) > 1/app.pdfAxes.Position(3)
                    %% Aspect ratio is off -- correct it
                    if img_ar > fig_ar %img is too tall -- reduce width
                        app.pdfAxes.Position(3) = round(app.pdfAxes.Position(4)/img_ar);
                    else
                        app.pdfAxes.Position(4) = round(app.pdfAxes.Position(3)*img_ar);
                    end
                end
                
                %%Check if there is already a rectangle
                for i = 1:length(app.pdfAxes.Children)
                    if strcmp(class(app.pdfAxes.Children(i)), 'images.roi.Rectangle')
                        rectloc = app.pdfAxes.Children(i).Position;
                    end
                end
                
                I = imshow(img, 'Parent', app.pdfAxes);
                app.pdfAxes.XLim = I.XData;
                app.pdfAxes.YLim = I.YData;
                
                if(exist('rectloc','var'))
                    h = drawrectangle(app.pdfAxes, 'Position', rectloc);
                else
                    h = drawrectangle(app.pdfAxes, 'Position', [.25*size(app.pdfOrigImage, [ 1 2]), .5*size(app.pdfOrigImage, [ 1 2])]);
                end
                
                h.LineWidth = 1;
                addprop(h, 'callingApp');
                h.callingApp = app;
                addlistener(h, 'ROIMoved', @pdfSelectionRectMoved);
                app.pdfSelectionRectMoved(h);
           
            else
                fig_ar = app.pdfImage.Position(4)/app.pdfImage.Position(3);
                if abs(img_ar - fig_ar) > 1/app.pdfImage.Position(3)
                    %% Aspect ratio is off -- correct it
                    if img_ar > fig_ar %img is too tall -- reduce width
                        app.pdfImage.Position(3) = round(app.pdfImage.Position(4)/img_ar);
                    else
                        app.pdfImage.Position(4) = round(app.pdfImage.Position(3)*img_ar);
                    end
                end
                app.pdfImage.ImageSource = img;
            end
        end
        
        function showPlot(app)
            if(app.ShowPlotMasksButton.Value == true)
                img = uint8(double(app.curPlotData.img) + app.plotMask);
            else
                 img = app.curPlotData.img;
            end
            img_ar = size(img,1)/size(img,2); %h/w

            fig_ar = app.plotImage.Position(4)/app.pdfImage.Position(3);
            if abs(img_ar - fig_ar) > 1/app.plotImage.Position(3)
                %% Aspect ratio is off -- correct it
                if img_ar > fig_ar %img is too tall -- reduce width
                    app.plotImage.Position(3) = round(app.plotImage.Position(4)/img_ar);
                else
                    app.plotImage.Position(4) = round(app.plotImage.Position(3)*img_ar);
                end
            end
            app.plotImage.ImageSource = img;
        end
        
        function checkValidForDigitizing(app)
            if app.MaxYValueEditField.Value > app.MinYValueEditField.Value &&...
                    app.MaxXValueEditField.Value > app.MinXValueEditField.Value && ...
                    ~isempty(app.curPlotData.img) %&& app.settings.useImageToolbox
                app.DigitzeManuallyButton.Enable = "on";
            else
                app.DigitzeManuallyButton.Enable = "off";
            end
        end
        
        function resetPlot(app)
            app.MaxYValueEditField.Value = 0; app.MaxYValueEditField.FontColor = [0 0 0];
            app.MinYValueEditField.Value = 0; app.MinYValueEditField.FontColor = [0 0 0];
            app.MaxXValueEditField.Value = 0; app.MaxXValueEditField.FontColor = [0 0 0];
            app.MinXValueEditField.Value = 0; app.MinXValueEditField.FontColor = [0 0 0];
            
            app.YaxisDropDown.Value = '<not selected>'; app.YaxisDropDown.FontColor = [0 0 0];
            app.XaxisDropDown.Value = '<not selected>'; app.XaxisDropDown.FontColor = [0 0 0];
            app.XSIprefixDropDown.Value = 'none'; app.XSIprefixDropDown.FontColor = [0 0 0];
            app.YSIprefixDropDown.Value = 'none'; app.YSIprefixDropDown.FontColor = [0 0 0];
        end

        
        function showDigitizedDataTable(app, dP)
            if ~isprop(app.digitizedResultsTable, 'dP')
                addprop(app.digitizedResultsTable, 'dP');
            end
            app.digitizedResultsTable.dP = dP;
                    
            app.digitizedResultsTable.Visible = "on";
            app.digitizedResultsDoneButton.Visible = "on";
            app.FindSimilarPlotsButton.Visible = "off";
            app.digitizedResultsTable.Data = [];
            
            Data = [];
                        
            for i = 1:length(dP.plotData)
                app.digitizedResultsTable.ColumnName{2*i} = dP.dataLabels{i};
                app.digitizedResultsTable.ColumnName{2*i-1} = 'X';
                
                curLength = size(Data,1);
                nVals = size(dP.plotData{i},1);
                Data(1:nVals, 2*i-1:2*i) = dP.plotData{i};
                if i > 1
                    if nVals > curLength
                        Data(curLength+1:nVals,1:2*(i-1)) = NaN;
                    elseif curLength > nVals
                        Data(nVals+1:curLength, 2*i-1:2*i) = NaN;
                    end
                end
            end 
            app.digitizedResultsTable.Data = Data;
            app.digitizedResultsTable.ColumnEditable = ones(1, 2*length(dP.plotData))==1;
        end
        
        
        function setupTreeValues(app)
            
%             types = {'None', 'Capacitance', 'Voltage', 'Current', 'Resistance', 'Energy', 'Charge', 'Temperature', 'Thermal Resistance', 'Time', 'Transconductance'};
% %             firstLetters = {'','C','V','I','R','E','Q','T','R','T','G'};
%             units = {'','F','V','A','Ohm','J','C',[char(176) 'C'],[char(176) 'C/W'], 's', 'A/V'};
            t = transistor();
            sigTypes = t.signalTypes;
            sigUnits = t.signalUnits;
            
            headerY = uitreenode(app.YaxisTree);
            headerY.Text = 'None';
            
            headerX = uitreenode(app.XaxisTree);
            headerX.Text = 'None';
            
            for i = 1:length(sigUnits)
                ind = find(strcmp(t.defaultUnits,sigUnits{i}));
                
                if ~isempty(ind)
                    headerY = uitreenode(app.YaxisTree);
                    headerY.Text = sigTypes{i};
                    
                    headerX = uitreenode(app.XaxisTree);
                    headerX.Text = sigTypes{i};
                    
                    
                    for j = 1:length(ind)
                        valY = uitreenode(headerY);
                        valY.Text = [t.knownParams{ind(j)} ', ' t.paramNames{ind(j)}];
                        
                        valX = uitreenode(headerX);
                        valX.Text = [t.knownParams{ind(j)} ', ' t.paramNames{ind(j)}];
                    end
                end
            end
        end
    end
    
    methods (Access = public)
        
        function grabitComplete(app, handle)
%             app.showGrabitData(handle);
             
            %% Load data
            digiPlot = app.curPlotData;
            digiPlot.loadGrabitData(handle);
            
            %% Plot Digitized Data over figure
            cla(app.plotAxes); app.digiTracesTable.Data = [];
            app.plotAxes.Position = app.plotImage.Position;
            digiPlot.plotDigitizedData(app.plotAxes)
            
            app.showDigitizedDataTable(digiPlot);


        end
        
        function reinitialize(app)
%             app.createComponents();
            app.initialize(app.datasheet);
        end
        
        
        function pdfSelectionRectMoved(app, rect)
            img = app.pdfOrigImage;
            plotImg = img(round(rect.Position(2)):round(sum(rect.Position([2 4]))), ...
                round(rect.Position(1)):round(sum(rect.Position([1 3]))), :);
%             app.plotLoc = rect.Position;
            app.curPlotData = digitizedPlot(app, plotImg, rect.Position, app.pdfIndex);
            
%             app.plotOrigImage = app.curPlotData.img;
            app.plotAxes.Position(4) = 0;
            app.plotMask = 0;
            app.showPlot();
            app.identifyPlot();
            app.FindSimilarPlotsButton.Enable = "on"; 
        end


        function results = pdfPage(app)
            results = app.pdfIndex;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialize(app, datasheet)
            eloc = mfilename('fullpath'); %executing location
            divs = strfind(eloc, '\');
            eloc = eloc(1:divs(end));
            cd(eloc)
            if ~isfolder('temp')
                mkdir(eloc, 'temp');
            end
            if ~isfolder('settings')
                mkdir(eloc, 'settings');
            end
            addpath("temp");
            addpath("settings");
            addpath("src");

            % [flist, plist] = matlab.codetools.requiredFilesAndProducts('plotDigitizer.mlapp') 
            
            %% Make plotAxes invisible
            app.plotAxes.Position(4) = 0;


            if(nargin < 2)
                % No file given
                app.dialogWait = 1;
                datasheetDialog(app);
                while (app.dialogWait == 1)
                    pause(1)
                end
            else
                app.datasheet = datasheet;
            end
            
            if ~exist("digitizerSetup.mat",'file')
                PDpreferences(app);
                while(~exist("digitizerSetup.mat",'file'))
                    pause(1);
                end
                load("digitizerSetup.mat", 'PDsettings');
            else
                load("digitizerSetup.mat", 'PDsettings');
            end
            
            assert(exist("PDsettings","var")==1, 'settings file not available');       
            
            app.settings = PDsettings;    %store settings loaded from mat file

            assert(isfield(app.settings, 'gsloc'), 'ghostscript location not given');
            assert(isfile(app.settings.gsloc), 'ghoscript file is incorrect')
            
            if ~isfield(app.settings, 'clargs')
                app.settings.clargs = '-sDEVICE=pngalpha -r300 -o';
            end
            
            if ~isfield(app.settings, 'useCVToolbox')
                app.settings.useCVToolbox = 0;
            end
            
            if ~isfield(app.settings, 'useImageToolbox')
                app.settings.useImageToolbox = 0;
            end
            
            %% Check MATLAB version and installed Toolboxes
%             hasImageToolbox = license('test','image_toolbox');
%             hasComputerVision = license('test','Video_and_Image_Blockset');
            v = ver;
            hasImageToolbox = license('test','image_toolbox') && ~isempty(intersect({v.Name}, 'Image Processing Toolbox'));
            hasComputerVision = license('test','Video_and_Image_Blockset') && ~isempty(intersect({v.Name}, 'Computer Vision Toolbox'));
           
            MLver = version;
            [si, ei] = regexp(MLver, '\(.*\)');
            MLversion = {str2double(MLver(si+2:ei-2)), MLver(ei-1)};
            
            canUseRectSelection = (MLversion{1} > 2019 || ...
                ((MLversion{1} == 2019) && MLversion{2} == 'b')) ...
                && hasImageToolbox;
            
           if ~canUseRectSelection
               app.settings.useRectSelection = 0;
           end
           
           if ~hasImageToolbox
               app.settings.useImageToolbox = 0;
           end
           
           if ~hasComputerVision
               app.settings.useCVToolbox = 0;
           end
            
            assert(prod(isfield(app.settings, {'gsloc', 'clargs', ...
                'useImageToolbox', 'useCVToolbox'}))==1, ...
                'Improperly formatted settings file');
            
            PDsettings = app.settings;
            save("settings\digitizerSetup.mat", 'PDsettings');
            
            %% Alter items shown based on settings
            app.pdfAxes.Visible = app.settings.useRectSelection;
            app.pdfImage.Visible = ~app.settings.useRectSelection;
            
            if app.settings.useRectSelection == 1
                app.pdfAxes.Visible = "on";
                app.pdfAxes.Position = [35,215,330,427];
                app.pdfImage.Visible = "off";
                app.pdfImage.Position = [35 215 0 0];
            else
                app.pdfAxes.Visible = "off";
                app.pdfAxes.Position = [35 215 0 0];
                app.pdfImage.Visible = "on";
                app.pdfImage.Position = [35,215,330,427];
            end
            
            app.setupTreeValues();

            if app.debug == 0
                app.loadDatasheetPDF();
            else
                app.findPDFfiles();
                app.pdfIndex = 3;
                app.pdfOrigImage = imread(app.pdfFiles{app.pdfIndex});
                app.showDatasheetPage();
                
                app.pdfClicks = [0.5212,    0.6573;    0.9556,    0.3763];
                app.plotSelectionFromPDF();
                app.pdfClicks = [];
            end
        end

        % Button pushed function: prevButton
        function prevButtonPushed(app, event)
            if(isempty(app.pdfFiles))
                app.findPDFfiles();
            end
            app.pdfIndex = app.pdfIndex - 1;
            if(app.pdfIndex <= 0)
                app.pdfIndex = length(app.pdfFiles);
            end
            app.pdfOrigImage = imread(app.pdfFiles{app.pdfIndex});
            app.pdfMask = 0;
            app.otherPlotBounds = [];
            app.showDatasheetPage();
        end

        % Image clicked function: pdfImage
        function pdfClick(app, event)
           clickLoc = get(0, 'PointerLocation'); % Origin is bottom-left
           windowLoc = app.DatasheetDigitizerUIFigure.Position; %bottom-left corner of figure from bottom-left corner of screen
           figureLoc = app.pdfImage.Position;  %bottom-left from bottom-left
           
           clickOnFigure = clickLoc - windowLoc(1:2);
           clickOnImage = clickOnFigure - figureLoc(1:2);
           
%            app.clickTimer = tic;
           if isempty(app.otherPlotBounds)
               app.pdfClicks = sortrows([app.pdfClicks; clickOnImage./figureLoc(3:4)]);
               imgDim = [size(app.pdfOrigImage, 2), size(app.pdfOrigImage, 1)];
               
               %% add location indicator
               R = cat(3,255, 0, 0);
               if size(app.pdfClicks,1) == 1
                   mask = app.pdfOrigImage;
                   r = round(size(app.pdfOrigImage,2)/50);
                   
                   loc = round(app.pdfClicks(1,:).*imgDim);
                   loc(:,2) = imgDim(2) - loc(:,2);
                   
                   xr = loc(1)-r:loc(1)+r;
                   xr(xr<1) = [];   xr(xr>size(app.pdfOrigImage,2)) = [];
                   mask(loc(2)-2:loc(2)+2, xr, :) = repmat(R,5, length(xr));
                   
                   yr = loc(2)-r:loc(2)+r;
                   yr(yr<1) = [];   yr(yr>size(app.pdfOrigImage,1)) = [];
                   mask(yr, loc(1)-2:loc(1)+2, :) = repmat(R, length(yr), 5);
                   try %% Occasional "Array dimesntions must match for binary op -- ?
                        app.pdfMask = double(mask) - double(app.pdfOrigImage);
                   catch
                       x=1;
                   end
                   app.showDatasheetPage();
               else
                   locs = round( app.pdfClicks.*repmat(imgDim,2,1) );
                   locs(:,2) = imgDim(2) - locs(:,2);
                   mask = app.pdfOrigImage;
                   rows = sort(locs(:,2));
                   cols = sort(locs(:,1));
                   mask(rows(1)-2:rows(1)+2,cols(1):cols(2),:) = repmat(R,5, cols(2)-cols(1)+1);
                   mask(rows(2)-2:rows(2)+2,cols(1):cols(2),:) = repmat(R,5, cols(2)-cols(1)+1);
                   
                   mask(rows(1):rows(2),cols(1)-2:cols(1)+2,:) = repmat(R,rows(2)-rows(1)+1,5);
                   mask(rows(1):rows(2),cols(2)-2:cols(2)+2,:) = repmat(R,rows(2)-rows(1)+1,5);
    %                mask(locs(1,2)-2:locs(1,2)+2,locs(1,1):locs(2,1),:) = repmat(R,5, locs(2,1)-locs(1,1)+1);
    %                mask(locs(2,2)-2:locs(2,2)+2,locs(1,1):locs(2,1),:) = repmat(R,5, locs(2,1)-locs(1,1)+1);
    %                
    %                mask(locs(1,2):locs(2,2),locs(1,1)-2:locs(1,1)+2,:) = repmat(R,locs(2,2)-locs(1,2)+1,5);
    %                mask(locs(1,2):locs(2,2),locs(2,1)-2:locs(2,1)+2,:) = repmat(R,locs(2,2)-locs(1,2)+1,5);
              
                   app.pdfMask = double(mask) - double(app.pdfOrigImage);
                   app.showDatasheetPage();
               end
           else
               %% Other plots idenfitifed, find which one was clicked
               pixelClick = [round(size(app.pdfOrigImage,2)*(clickOnImage(1)/figureLoc(3))), ...
                        round(size(app.pdfOrigImage,1)*(1-clickOnImage(2)/figureLoc(4)))];            

               
               bounds = [app.otherPlotBounds(:,1:2), app.otherPlotBounds(:,1:2) + app.otherPlotBounds(:,3:4)];
               newPlot = find(pixelClick(1) > bounds(:,1) & pixelClick(1) < bounds(:,3) & ...
                   pixelClick(2) > bounds(:,2) & pixelClick(2) < bounds(:,4));
               if ~isempty(newPlot)
%                    locs = [app.otherPlotBounds(newPlot, 1:2);
%                        app.otherPlotBounds(newPlot, 1:2) + app.otherPlotBounds(newPlot, 3:4)];

                   app.pdfClicks = [bounds(newPlot,1:2); bounds(newPlot,3:4)]./ ...
                       repmat([size(app.pdfOrigImage,2), size(app.pdfOrigImage,1)],2,1);
                   app.pdfClicks(:,2) = 1- app.pdfClicks(:,2);
               end
           end
           
           
           if sum(size(app.pdfClicks) == [2,2]) == 2
               app.plotAxes.Position(4) = 0;
               app.plotSelectionFromPDF();
               app.pdfClicks = [];
           end
        end

        % Image clicked function: plotImage
        function plotClick(app, event)
% % %             if isempty(app.otherPlotBounds)
% % %                 return
% % %             else
% % %                 clickLoc = get(0, 'PointerLocation'); % Origin is bottom-left
% % %                 windowLoc = app.DatasheetDigitizerUIFigure.Position; %bottom-left corner of figure from bottom-left corner of screen
% % %                 figureLoc = app.pdfAxes.Position;  %bottom-left from bottom-left
% % %                 
% % %                 clickOnFigure = clickLoc - windowLoc(1:2);
% % %                 clickOnImage = clickOnFigure - figureLoc(1:2);
% % %                 
% % %                 %% Other plots idenfitifed, find which one was clicked
% % %                 pixelClick = [round(size(app.pdfOrigImage,2)*(clickOnImage(1)/figureLoc(3))), ...
% % %                         round(size(app.pdfOrigImage,1)*(1-clickOnImage(2)/figureLoc(4)))];            
% % %                 
% % %                 
% % %                 bounds = [app.otherPlotBounds(:,1:2), app.otherPlotBounds(:,1:2) + app.otherPlotBounds(:,3:4)];
% % %                 newPlot = find(pixelClick(1) > bounds(:,1) & pixelClick(1) < bounds(:,3) & ...
% % %                    pixelClick(2) > bounds(:,2) & pixelClick(2) < bounds(:,4));
% % %                 if ~isempty(newPlot)
% % %                    pdfSelectionRectMoved(app, bounds(newPlot,:))
% % %                 end
% % %             end
        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            if(isempty(app.pdfFiles))
                app.findPDFfiles();
            end
            app.pdfIndex = app.pdfIndex + 1;
            if(app.pdfIndex > length(app.pdfFiles))
                app.pdfIndex = 1;
            end
            app.pdfOrigImage = imread(app.pdfFiles{app.pdfIndex});
            app.pdfMask = 0;
            app.otherPlotBounds = [];
            app.showDatasheetPage();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.DatasheetDigitizerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {715, 715};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {399, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: DigitzeManuallyButton
        function startDigitizer(app, event)
            if ~isempty(app.curPlotData.img)
                imwrite(app.curPlotData.img, 'temp/tempPlot.png');

                grabit([pwd '\' 'temp\tempPlot.png'], app);
            end
        end

        % Menu selected function: PreferencesMenu
        function filePreferences(app, event)
            app.dialogWait = 1;
            PDpreferences(app);
            while app.dialogWait == 1
                pause(1)
            end         
        end

        % Menu selected function: NewMenu
        function fileNew(app, event)
            app.dialogWait = 1;
            datasheetDialog(app);
            while app.dialogWait == 1
                pause(1)
            end
            app.loadDatasheetPDF();
        end

        % Value changed function: MaxXValueEditField, 
        % MaxYValueEditField, MinXValueEditField, MinYValueEditField
        function plotFieldEdited(app, event)
            event.Source.FontColor = [0 0 0];
            
            app.checkValidForDigitizing();
        end

        % Button pushed function: AutoDigitizebyColorButton
        function digitizeColoredPlot(app, event)
            if app.settings.useImageToolbox
            try
                digiPlot = app.curPlotData;
                
                % Use user-supplied # of colors if available, otherwise guess
                if app.NTracesEditField.Value == 0
                    nColors = digiPlot.imgTraceColors();
                    app.NTracesEditField.Value = nColors-1;
                    app.NTracesEditField.FontColor = [1 0 0];
                else
                    nColors = app.NTracesEditField.Value+1;
                    app.NTracesEditField.FontColor = [0 0 0];
                end
                
                % Find traces by kmeans clustering
                digiPlot.colorClustering(nColors);
                
                % plot Data and show Data table
                if ~isempty(digiPlot.plotData)
                    cla(app.plotAxes); 
                    app.plotAxes.Position = app.plotImage.Position;
                    digiPlot.plotDigitizedData(app.plotAxes);
                    app.showDigitizedDataTable(digiPlot);
                end
                
            catch ME
                warning("Automatic plot digitization based on color failed");
                getReport(ME)
            end
            end
        end

        % Button pushed function: digitizedResultsDoneButton
        function digitizedResultsEditingDone(app, event)
            app.digitizedResultsTable.Visible = "off";
            app.digitizedResultsDoneButton.Visible = "off";
            app.FindSimilarPlotsButton.Visible = "on";
            app.NTracesEditField.Value = 0;
            if isempty(app.savedPlotData)
                app.savedPlotData = app.curPlotData;
            else
                app.savedPlotData =[app.savedPlotData, app.curPlotData];
            end
            
            xind = min([length(app.curPlotData.axisLabels{1}), strfind(app.curPlotData.axisLabels{1}, ',')-1]);
            yind = min([length(app.curPlotData.axisLabels{2}), strfind(app.curPlotData.axisLabels{2}, ',')-1]);
            plotName = [app.curPlotData.axisLabels{2}(1:yind) '-vs-' app.curPlotData.axisLabels{1}(1:xind) ];
            
            if isempty(app.digiTracesTable.Data)
                app.digiTracesTable.Data = {
                    plotName, ...
                    num2str(length(app.curPlotData.plotData)), ...
                    'none', ...
                    'none'};
            else
                app.digiTracesTable.Data = [app.digiTracesTable.Data; ...
                    {plotName}, ...
                    {num2str(length(app.curPlotData.plotData))}, ...
                    {'none'}, ...
                    {'none'}];
            end
            
        end

        % Cell selection callback: digitizedResultsTable
        function showTableCellOnPlot(app, event)
            delete(app.displayPoints);
            indices = event.Indices;
            
            dP = app.digitizedResultsTable.dP;
            
            % Ignore any values selected from the x-columns
            xo = mod(indices(:,2),2) == 1;
            indices(xo,:) = [];
            traces = unique(indices(:,2))/2;
            
            if ~isempty(traces)
                % Find corresponding img location for each datapoint
                for i = traces'
                    ind = indices(indices(:,2) == 2*i,:);
                    ind = ind(:,1);
                    xPlotData = app.digitizedResultsTable.Data(ind,i*2-1);
                    
                    [~,locs,~] = intersect(dP.plotData{i}(:,1), xPlotData);
                    ximgData = dP.imgData{i}(locs,1);
                    yimgData = dP.imgData{i}(locs,2);
                    
                    refTrace = findobj(app.plotAxes.Children,'Type','line');
                    traceColor = refTrace(end-i+1).Color;
                    app.displayPoints = plot(app.plotAxes, ximgData , yimgData, 'o', 'LineWidth', 3, 'MarkerEdgeColor', traceColor);
                end
            end

        end

        % Cell edit callback: digitizedResultsTable
        function updateDigizedDataFromTable(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            dP = app.digitizedResultsTable.dP;
            xy = 2- mod(indices(:,2),2);
            
            for i = 1:size(indices,1)
                ind = indices(i,:);
                trace = ceil(ind(2)/2);
                dP.plotData{trace}(ind(1), xy) = newData(i);
                
%                 refTrace = findobj(app.plotAxes.Children,'Type','line');
            end
            dP.convert(2);
            cla(app.plotAxes);
            dP.plotDigitizedData(app.plotAxes);
            
            app.showTableCellOnPlot(event)
        end

        % Button pushed function: FindSimilarPlotsButton
        function findOtherPlots(app, event)
            bboxes = app.curPlotData.findSimilarPlots(app.pdfOrigImage);
            app.otherPlotBounds = bboxes;
            
            labels = cellstr(num2str([1:size(bboxes,1)]'))';
            
            mask = insertObjectAnnotation(app.pdfOrigImage, 'rectangle', ...
                        bboxes, labels, ...
                        'Color', 'blue');
            app.pdfMask = double(mask) - double(app.pdfOrigImage);
            app.showDatasheetPage();
            
            if app.settings.useRectSelection == 1

                for i = length(app.pdfAxes.Children):-1:1
                    if strcmp(class(app.pdfAxes.Children(i)), 'images.roi.Rectangle')
                        app.pdfAxes.Children(i).InteractionsAllowed = 'translate';
                    end
                end
                
                roi = drawpoint(app.pdfAxes, 'Position', [100 100]);
                

%                 updateNextPlot(x,y);
            end
            
        end

        % Button pushed function: DoneButton
        function exitButton(app, event)
            outData.file = app.datasheet;
            outData.plotData = app.savedPlotData;

            if isempty(app.PartNumberEditField.Value)
                app.InstructionsTextArea.Value = "Part Number is missing";
                return
            end
            
            outData.partNo = app.PartNumberEditField.Value;
            
            outDataName = [outData.partNo '_datasheet'];
            if exist(outDataName, 'var') == 1
                app.InstructionsTextArea.Value = ['variable name ' outDataName 'already exists in the workspace' ];
                return
            end
            
            for i = 1:length(outData.plotData)
                outData.plotData(i).callingApp = [];
                if app.settings.useImageToolbox == 1
                    outData.plotData(i).img = imresize(rgb2gray(outData.plotData(i).img),[250, 250]);
                else
                    outData.plotData(i).img = [];
                end
            end
            
            assignin('base',outDataName,outData);
            delete(app);
        end

        % Value changed function: ShowPlotMasksButton
        function showPlotMaskToggle(app, event)
%             value = app.ShowPlotMasksButton.Value;
%             if value
%                 app.
%             end
            app.showPlot();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DatasheetDigitizerUIFigure = uifigure('Visible', 'off');
            app.DatasheetDigitizerUIFigure.Name = 'Datasheet Digitizer';
            app.DatasheetDigitizerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.DatasheetDigitizerUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @fileNew, true);
            app.NewMenu.Text = 'New';
            app.PreferencesMenu = uimenu(app.FileMenu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @filePreferences, true);
            app.PreferencesMenu.Text = 'Preferences';
            app.GridLayout = uigridlayout(app.DatasheetDigitizerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.pdfImage = uiimage(app.LeftPanel);
            app.pdfImage.ImageClickedFcn = createCallbackFcn(app, @pdfClick, true);
            app.prevButton = uibutton(app.LeftPanel, 'push');
            app.prevButton.ButtonPushedFcn = createCallbackFcn(app, @prevButtonPushed, true);
            app.prevButton.Text = 'prev';
            app.InstructionsTextAreaLabel = uilabel(app.LeftPanel);
            app.InstructionsTextAreaLabel.Visible = 'off';
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.InstructionsTextArea = uitextarea(app.LeftPanel);
            app.PartNumberEditFieldLabel = uilabel(app.LeftPanel);
            app.PartNumberEditFieldLabel.Text = 'Part Number';
            app.PartNumberEditField = uieditfield(app.LeftPanel, 'text');
            app.nextButton = uibutton(app.LeftPanel, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = 'next';
            app.DatasheetLabel = uilabel(app.LeftPanel);
            app.DatasheetLabel.Text = 'Datasheet';
            app.pdfAxes = uiaxes(app.LeftPanel);
            title(app.pdfAxes, '')
            xlabel(app.pdfAxes, '')
            ylabel(app.pdfAxes, '')
            app.pdfAxes.XTick = [];
            app.pdfAxes.YTick = [];
            app.digitizedResultsTable = uitable(app.LeftPanel);
            app.digitizedResultsTable.ColumnName = {'X'; 'Y1'};
            app.digitizedResultsTable.RowName = {};
            app.digitizedResultsTable.CellEditCallback = createCallbackFcn(app, @updateDigizedDataFromTable, true);
            app.digitizedResultsTable.CellSelectionCallback = createCallbackFcn(app, @showTableCellOnPlot, true);
            app.digitizedResultsTable.Visible = 'off';
            app.digitizedResultsDoneButton = uibutton(app.LeftPanel, 'push');
            app.digitizedResultsDoneButton.ButtonPushedFcn = createCallbackFcn(app, @digitizedResultsEditingDone, true);
            app.digitizedResultsDoneButton.Visible = 'off';
            app.digitizedResultsDoneButton.Text = 'Done';
            app.FindSimilarPlotsButton = uibutton(app.LeftPanel, 'push');
            app.FindSimilarPlotsButton.ButtonPushedFcn = createCallbackFcn(app, @findOtherPlots, true);
            app.FindSimilarPlotsButton.Enable = 'off';
            app.FindSimilarPlotsButton.Text = 'Find Similar Plots';
            app.RightPanel = uipanel(app.GridLayout);
            app.plotImage = uiimage(app.RightPanel);
            app.plotImage.ImageClickedFcn = createCallbackFcn(app, @plotClick, true);
            app.DigitzeManuallyButton = uibutton(app.RightPanel, 'push');
            app.DigitzeManuallyButton.ButtonPushedFcn = createCallbackFcn(app, @startDigitizer, true);
            app.DigitzeManuallyButton.Enable = 'off';
            app.DigitzeManuallyButton.Text = 'Digitze Manually';
            app.XaxisDropDownLabel = uilabel(app.RightPanel);
            app.XaxisDropDownLabel.Enable = 'off';
            app.XaxisDropDownLabel.Visible = 'off';
            app.XaxisDropDownLabel.Text = 'X-axis';
            app.XaxisDropDown = uidropdown(app.RightPanel);
            app.XaxisDropDown.Items = {'<not selected>', 'Capacitance', 'Voltage', 'Current', 'Resistance', 'Energy', 'Charge', 'Temperature', 'Thermal Resistance', 'Time'};
            app.XaxisDropDown.Enable = 'off';
            app.XaxisDropDown.Visible = 'off';
            app.XaxisDropDown.Value = '<not selected>';
            app.YaxisDropDownLabel = uilabel(app.RightPanel);
            app.YaxisDropDownLabel.Enable = 'off';
            app.YaxisDropDownLabel.Visible = 'off';
            app.YaxisDropDownLabel.Text = 'Y-axis';
            app.YaxisDropDown = uidropdown(app.RightPanel);
            app.YaxisDropDown.Items = {'<not selected>', 'Capacitance', 'Voltage', 'Current', 'Resistance', 'Energy', 'Charge', 'Temperature', 'Thermal Resistance', 'Time', ''};
            app.YaxisDropDown.Enable = 'off';
            app.YaxisDropDown.Visible = 'off';
            app.YaxisDropDown.Value = '<not selected>';
            app.SIprefixDropDown_2Label = uilabel(app.RightPanel);
            app.SIprefixDropDown_2Label.Text = 'SI prefix';
            app.XSIprefixDropDown = uidropdown(app.RightPanel);
            app.XSIprefixDropDown.Items = {'f', 'p', 'n', 'u', 'm', 'none', 'k', 'M', 'G'};
            app.XSIprefixDropDown.Value = 'none';
            app.NormalizedYaxisCheckBox = uicheckbox(app.RightPanel);
            app.NormalizedYaxisCheckBox.Text = 'Normalized  Y-axis';
            app.LogarithmicYaxisCheckBox = uicheckbox(app.RightPanel);
            app.LogarithmicYaxisCheckBox.Text = 'Logarithmic Y-axis';
            app.NormalizedXaxisCheckBox = uicheckbox(app.RightPanel);
            app.NormalizedXaxisCheckBox.Text = 'Normalized  X-axis';
            app.LogarithmicXaxisCheckBox = uicheckbox(app.RightPanel);
            app.LogarithmicXaxisCheckBox.Text = 'Logarithmic X-axis';
            app.MaxYValueEditFieldLabel = uilabel(app.RightPanel);
            app.MaxYValueEditFieldLabel.Text = 'Max Y-Value';
            app.MaxYValueEditField = uieditfield(app.RightPanel, 'numeric');
            app.MaxYValueEditField.ValueChangedFcn = createCallbackFcn(app, @plotFieldEdited, true);
            app.MinYValueEditFieldLabel = uilabel(app.RightPanel);
            app.MinYValueEditFieldLabel.Text = 'Min Y-Value';
            app.MinYValueEditField = uieditfield(app.RightPanel, 'numeric');
            app.MinYValueEditField.ValueChangedFcn = createCallbackFcn(app, @plotFieldEdited, true);
            app.MaxXValueEditFieldLabel = uilabel(app.RightPanel);
            app.MaxXValueEditFieldLabel.Text = 'Max X-Value';
            app.MaxXValueEditField = uieditfield(app.RightPanel, 'numeric');
            app.MaxXValueEditField.ValueChangedFcn = createCallbackFcn(app, @plotFieldEdited, true);
            app.MinXValueEditFieldLabel = uilabel(app.RightPanel);
            app.MinXValueEditFieldLabel.Text = 'Min X-Value';
            app.MinXValueEditField = uieditfield(app.RightPanel, 'numeric');
            app.MinXValueEditField.ValueChangedFcn = createCallbackFcn(app, @plotFieldEdited, true);
            app.skewedaxesCheckBox = uicheckbox(app.RightPanel);
            app.skewedaxesCheckBox.Enable = 'off';
            app.skewedaxesCheckBox.Text = 'skewed axes';
            app.digiTracesTable = uitable(app.RightPanel);
            app.digiTracesTable.ColumnName = {'Plot'; 'Number of Traces'; 'Curves'; 'Test Conditions'};
            app.digiTracesTable.RowName = {};
            app.digiTracesTable.ColumnEditable = [false false false];
            app.SIprefixDropDown_2Label_2 = uilabel(app.RightPanel);
            app.SIprefixDropDown_2Label_2.Text = 'SI prefix';
            app.YSIprefixDropDown = uidropdown(app.RightPanel);
            app.YSIprefixDropDown.Items = {'f', 'p', 'n', 'u', 'm', 'none', 'k', 'M', 'G'};
            app.YSIprefixDropDown.Value = 'none';
            app.plotAxes = uiaxes(app.RightPanel);
            title(app.plotAxes, '')
            xlabel(app.plotAxes, '')
            ylabel(app.plotAxes, '')
            app.plotAxes.XTick = [];
            app.plotAxes.YTick = [];
            app.AutoDigitizebyColorButton = uibutton(app.RightPanel, 'push');
            app.AutoDigitizebyColorButton.ButtonPushedFcn = createCallbackFcn(app, @digitizeColoredPlot, true);
            app.AutoDigitizebyColorButton.Text = 'Auto-Digitize by Color';
            app.ofTracesLabel = uilabel(app.RightPanel);
            app.ofTracesLabel.Text = '# of Traces';
            app.NTracesEditField = uieditfield(app.RightPanel, 'numeric');
            app.DoneButton = uibutton(app.RightPanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @exitButton, true);
            app.DoneButton.Text = 'Done';
            app.ShowPlotMasksButton = uibutton(app.RightPanel, 'state');
            app.ShowPlotMasksButton.ValueChangedFcn = createCallbackFcn(app, @showPlotMaskToggle, true);
            app.ShowPlotMasksButton.Text = 'Show Plot Masks';
            app.ShowPlotMasksButton.Value = true;
            app.YaxisTree = uitree(app.RightPanel);
            app.XaxisTree = uitree(app.RightPanel);
            app.XTreeLabel = uieditfield(app.RightPanel, 'text');
            app.XTreeLabel.Editable = 'off';
            app.XTreeLabel.Value = 'X-axis';
            app.YTreeLabel = uieditfield(app.RightPanel, 'text');
            app.YTreeLabel.Editable = 'off';
            app.YTreeLabel.Value = 'Y-axis';
            % Show the figure after all components are created
            app.DatasheetDigitizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotDigitizer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DatasheetDigitizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)initialize(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DatasheetDigitizerUIFigure)
        end
    end
end