classdef plotDigitizerMessedUp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DatasheetDigitizerUIFigure  matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        NewMenu                     matlab.ui.container.Menu
        PreferencesMenu             matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        pdfImage                    matlab.ui.control.Image
        prevButton                  matlab.ui.control.Button
        InstructionsTextAreaLabel   matlab.ui.control.Label
        InstructionsTextArea        matlab.ui.control.TextArea
        PartNumberEditFieldLabel    matlab.ui.control.Label
        PartNumberEditField         matlab.ui.control.EditField
        nextButton                  matlab.ui.control.Button
        DatasheetLabel              matlab.ui.control.Label
        pdfAxes                     matlab.ui.control.UIAxes
        digitizedResultsTable       matlab.ui.control.Table
        digitizedResultsDoneButton  matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        plotImage                   matlab.ui.control.Image
        StartDigitizingButton       matlab.ui.control.Button
        XaxisDropDownLabel          matlab.ui.control.Label
        XaxisDropDown               matlab.ui.control.DropDown
        YaxisDropDownLabel          matlab.ui.control.Label
        YaxisDropDown               matlab.ui.control.DropDown
        SIprefixDropDown_2Label     matlab.ui.control.Label
        XSIprefixDropDown           matlab.ui.control.DropDown
        NormalizedYaxisCheckBox     matlab.ui.control.CheckBox
        LogarithmicYaxisCheckBox    matlab.ui.control.CheckBox
        NormalizedXaxisCheckBox     matlab.ui.control.CheckBox
        LogarithmicXaxisCheckBox    matlab.ui.control.CheckBox
        MaxYValueEditFieldLabel     matlab.ui.control.Label
        MaxYValueEditField          matlab.ui.control.NumericEditField
        MinYValueEditFieldLabel     matlab.ui.control.Label
        MinYValueEditField          matlab.ui.control.NumericEditField
        MaxXValueEditFieldLabel     matlab.ui.control.Label
        MaxXValueEditField          matlab.ui.control.NumericEditField
        MinXValueEditFieldLabel     matlab.ui.control.Label
        MinXValueEditField          matlab.ui.control.NumericEditField
        skewedaxesCheckBox          matlab.ui.control.CheckBox
        digiTracesTable             matlab.ui.control.Table
        SIprefixDropDown_2Label_2   matlab.ui.control.Label
        YSIprefixDropDown           matlab.ui.control.DropDown
        plotAxes                    matlab.ui.control.UIAxes
        AutoDigitizebyColorButton   matlab.ui.control.Button
        ofTracesLabel               matlab.ui.control.Label
        NTRacesEditField            matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        settings
        
        pdfFiles % cell array of filenames of all png pages of pdf datasheet
        pdfIndex = 1
        
        pdfClicks
        clickTimer = 0 % timer between two clicks
        
        pdfSelectionRect % Description
        
        pdfOrigImage
        pdfMask = 0
        plotMask = 0
        
        displayPoints % Markers for post-digized table data selection
        
        curPlotData
        savedPlotData
    end
    
    properties (Access = public)
        dialogWait = 0 % flag to indicate that a dialog window is open
        datasheet 
        plotOrigImage
        imgAxes 
    end
    
    methods (Access = private)
        
        function findPDFfiles(app)
%             fn =  app.pdfImage.ImageSource;
%             [si, ei] = regexp(fn, '^.+-(?=(\d)+\.)');
%             fnTemplate = app.pdfImage.ImageSource(si:ei);

            
            S = dir('temp/datasheet-p*.png');
            app.pdfFiles = {S.name};
            if ~isempty(app.pdfFiles)
                app.pdfIndex = 1;
%                 if ~app.settings.useImageToolbox
%                     app.pdfImage.ImageSource = app.pdfFiles{app.pdfIndex};
%                 else
%                     img = imread(app.pdfFiles{app.pdfIndex});
%                     imshow(img, 'Parent', app.pdfAxes);
%                 end
                app.pdfOrigImage = imread(app.pdfFiles{app.pdfIndex});
                app.showDatasheetPage();
            else
                app.pdfIndex = 0;
            end
        end
        
        function plotSelectionFromPDF(app)
%             fn = app.pdfImage.ImageSource;
%             pageData = imread(fn);
            pageData = app.pdfOrigImage;
            app.plotMask = 0;
            
            cols = sort(round(size(pageData,2)*(app.pdfClicks(:,1))));
            rows = sort(round(size(pageData,1)*(1-app.pdfClicks(:,2))));
 
%             selection = zeros(size(pageData,1), size(pageData,2));
%             selection(rows(1):rows(2), cols(1):cols(2)) = 1;
%             selection = repmat(selection,1,1,3);
            imgSelection = pageData(rows(1):rows(2), cols(1):cols(2),:);
%             imgSelection = pageData(selection==1);
%             app.plotImage.ImageSource = imgSelection;
            app.plotOrigImage = imgSelection;
            
            app.identifyPlot();
            
            %% highlight selection on pdf
%             highlightedPage = pageData;
%             highlightedPage([rows(1), rows(2)], cols(1):cols(2),:) = 0;
%             highlightedPage(rows(1):rows(2), [cols(1),cols(2)],:) = 0;
%             app.pdfImage.ImageSource = highlightedPage;
        end
        
        function loadDatasheetPDF(app)
            
            if ~strcmp(app.datasheet(end-3:end), '.pdf')
                error('Datasheet given must be a direct link to a PDF')
            end
            
            if strcmp(app.datasheet(1:4), 'http')
                % first check if it is already downloaded
                loc = find(app.datasheet == '/', 1, 'last');
                fn = ['temp\' app.datasheet(loc+1:end)];
                if ~isfile(fn)
                    outfilename = websave(fn,app.datasheet);
                    app.datasheet = outfilename;
                else
                    %already downloaded it
                    app.datasheet = fn;
                end
                
            end

            %% Parse PDF into individual page images
            % First delete any old ones
%             app.pdfImage.ImageSource = 'temp\datasheet-p001.png';
            if(isfile(app.datasheet))
                app.findPDFfiles;
                for i = 1:length(app.pdfFiles)
                    fn = ['temp\' app.pdfFiles{i}];
                    delete(fn);
    %                 display(['temp\' app.pdfFiles{i}])
                end
            end
            
            % Then create pngs of each page
%             load("digitizerSetup.mat");
            cmd = ['"' char(app.settings.gsloc) '" ' char(app.settings.clargs) ' temp\datasheet-p%03d.png ' char(app.datasheet)];
            system(cmd);
            
            app.pdfOrigImage = imread('temp\datasheet-p001.png');
            app.showDatasheetPage();
            
            %% Testing Axes approach

%             FullImageUIAxes = axes(app.DatasheetDigitizerUIFigure, 'Position',app.pdfAxes.Position, 'XTick', [], 'YTick', [], 'Box', 'on');
%     
%             imshow(img, 'Parent', FullImageUIAxes);
%             FullImageUIAxes.Visible = true;
%             h = drawrectangle(FullImageUIAxes);
%             addlistener(h,'MovingROI', @(varargin)ROIMoving(app, h));

% app.FullImageUIAxes = axes(app.DatasheetDigitizerUIFigure, 'Units', 'Pixels', 'Position',[166,84,750,744], 'XTick', [], 'YTick', []);
% app.ROI = drawrectangle(app.FullImageUIAxes, 'Position', [app.ROIposition(1),app.ROIposition(2),app.ROIposition(3),app.ROIposition(4)], 'LineWidth', [1], 'Color', [1 0 0]);
% addlistener(app.ROI,'MovingROI', @(varargin)ROIMoving(app, app.ROI));

        end
        
        function identifyPlot(app)
            % detect any text on the image
%             ocrResults = ocr(img);

            app.plotMask = 0;
            app.resetPlot();
            
            % Look for bounding boxes of plot
            colDark = sum(app.plotOrigImage, [1,3]);
            rowDark = sum(app.plotOrigImage, [2,3]);
            
            % Block out outer 2.5% and inner 60%
            colDark(ceil(size(app.plotOrigImage,2)*.2):floor(size(app.plotOrigImage,2)*.8)) = inf;
            colDark(1:floor(size(app.plotOrigImage,2)*.025)) = inf;
            colDark(ceil(size(app.plotOrigImage,2)*.975):size(app.plotOrigImage,2)) = inf;
            rowDark(ceil(size(app.plotOrigImage,1)*.2):floor(size(app.plotOrigImage,1)*.8)) = inf;
            rowDark(1:floor(size(app.plotOrigImage,1)*.025)) = inf;
            rowDark(ceil(size(app.plotOrigImage,1)*.973):size(app.plotOrigImage,1)) = inf;
            
            % Find rows and cols that look most like horizontal/vertical
            % black lines
            [colc,coli] = mink(colDark, 10);
            [rowc,rowi] = mink(rowDark, 10);
            
%             colLeft = coli(find(coli < size(app.plotOrigImage,2)*.2, 1, 'first'));
%             colRight = coli(find(coli > size(app.plotOrigImage,2)*.8, 1, 'first'));
%             rowTop = rowi(find(rowi < size(app.plotOrigImage,1)*.2, 1, 'first'));
%             rowBottom = rowi(find(rowi > size(app.plotOrigImage,1)*.8, 1, 'first'));
            
            colLeft = coli(coli < size(app.plotOrigImage,2)/2)';
            if ~isempty(colLeft)
                relColor = colc(coli < size(app.plotOrigImage,2)/2)/mean(colc(coli < size(app.plotOrigImage,2)/2));
                colLeft = colLeft(relColor - min(relColor) < 0.05);
                colLeft = min(colLeft);
            end
            
            colRight = coli(coli > size(app.plotOrigImage,2)/2)';
            if ~isempty(colRight)
                relColor = colc(coli > size(app.plotOrigImage,2)/2)/mean(colc(coli > size(app.plotOrigImage,2)/2));
                colRight = colRight(relColor - min(relColor) < 0.05);
                colRight = max(colRight);
            end
            
            rowTop = rowi(rowi < size(app.plotOrigImage,1)/2)';
            if ~isempty(colLeft)
                relColor = rowc(rowi < size(app.plotOrigImage,1)/2)/mean(rowc(rowi < size(app.plotOrigImage,1)/2));
                rowTop = rowTop(relColor - min(relColor) < 0.05);
                rowTop = min(rowTop);
            end
            
            rowBottom = rowi(rowi > size(app.plotOrigImage,2)/2)';
            if ~isempty(rowBottom)
                relColor = rowc(rowi > size(app.plotOrigImage,1)/2)/mean(rowc(rowi > size(app.plotOrigImage,1)/2));
                rowBottom = rowBottom(relColor - min(relColor) < 0.05);
                rowBottom = max(rowBottom);
            end

            
            %% other thing
%             topRows = ceil(size(app.plotOrigImage,1)*.025):floor(size(app.plotOrigImage,1)*.2);
%             bottomRows = ceil(size(app.plotOrigImage,1)*.8):floor(size(app.plotOrigImage,1)*.975));
%             leftCols = ceil(size(app.plotOrigImage,2)*.025):floor(size(app.plotOrigImage,2)*.2);
%             rightCols = ceil(size(app.plotOrigImage,2)*.8):floor(size(app.plotOrigImage,2)*.975));
%             
%             colDark = sum(app.plotOrigImage, [1,3]);
%             rowDark = sum(app.plotOrigImage, [2,3]);
% 
%             colDark(ceil(size(app.plotOrigImage,2)*.2):floor(size(app.plotOrigImage,2)*.8)) = inf;
%             rowDark(ceil(size(app.plotOrigImage,1)*.2):floor(size(app.plotOrigImage,1)*.8)) = inf;
%             
%             test = colDark(leftCols);
%             [~,mins] = mink(test,10)
%             colLeft = find()

%             colLeft = coli(find(...
%                 (coli < size(app.plotOrigImage,2)*.2) & (coli > size(app.plotOrigImage,2)*.025), ...
%                 1, 'first'));
%             colRight = coli(find( ...
%                 (coli > size(app.plotOrigImage,2)*.8) & (coli < size(app.plotOrigImage,2)*.975), ...
%                 1, 'first'));
%             rowTop = rowi(find(...
%                 (rowi < size(app.plotOrigImage,1)*.2) & (rowi > size(app.plotOrigImage,1)*.025), ...
%                 1, 'first'));
%             rowBottom = rowi(find(...
%                 (rowi > size(app.plotOrigImage,1)*.8) & (rowi < size(app.plotOrigImage,1)*.975), ...
%                 1, 'first'));
            
            app.imgAxes = [colLeft, colRight, rowBottom, rowTop];
            
            if length(app.imgAxes) == 4
                %Hopefully we found them...
                mask = app.plotOrigImage;
                mask(rowTop-2:rowTop+2,colLeft:colRight,1) = 255;
                mask(rowTop-2:rowTop+2,colLeft:colRight,2:3) = 0;
                mask(rowBottom-2:rowBottom+2,colLeft:colRight,1) = 255;
                mask(rowBottom-2:rowBottom+2,colLeft:colRight,2:3) = 0;
                
                mask(rowTop:rowBottom,colLeft-2:colLeft+2,1) = 255;
                mask(rowTop:rowBottom,colLeft-2:colLeft+2,2:3) = 0;
                mask(rowTop:rowBottom,colRight-2:colRight+2,1) = 255;
                mask(rowTop:rowBottom,colRight-2:colRight+2,2:3) = 0;
                
%                 app.plotImage.ImageSource = img;    % Highlight the found axes on the image in Red
                app.plotMask = double(mask) - double(app.plotOrigImage);
                app.showPlot();
                
                %% Search for axis max/min
                if(app.settings.useCVToolbox)
                try
                    r = round(min(size(app.plotOrigImage,1), size(app.plotOrigImage,2))*.1);
                    % Approx locations of minimum/maximum axis labels
                    % [x y width height]
                    rois = [colLeft-r, rowTop-r/2, r, r;
                        colLeft-r, rowBottom-r/2, r, r;
                        colLeft-r/2, rowBottom, r, r;
                        colRight-r/2, rowBottom, r, r;
                        ];
                    
                    % Keep everything within the image extents
%                     rois = max(rois,repmat([0,r,0,0],4,1));
%                     rois = min(rois, repmat([size(app.plotOrigImage,2)-r, size(app.plotOrigImage,1)-r, nan, nan],4,1));
%                     
                    % Regions for axis labels
                    rois = [rois;
                        1, rowBottom, size(app.plotOrigImage,2)-2, size(app.plotOrigImage,1)-rowBottom-1;  %
                        1, 1, colLeft, size(app.plotOrigImage,1)-2;
                        ];
                    
                    % Keep everything within the image extents
                    len = size(rois,1);
                    rois = min(rois, [size(app.plotOrigImage,2)*ones(len,1) - rois(:,3), ...
                        size(app.plotOrigImage,1)*ones(len,1) - rois(:,4), inf*ones(len,2) ]);
                    rois = max(rois, zeros(len,4) );
                    
%                     rois(:,2) = size(app.plotOrigImage,1) - rois(:,2);  %flip vertical axis for ocr

                    % Check for recognizeable text
                    ocrResults = ocr(app.plotOrigImage, rois);
                    
                    % For Y-axis label, rotate the image to make text horizontal
                    ocrResults = [ocrResults; ocr(rot90(app.plotOrigImage,3), [rois(6,2) rois(6,1) rois(6,4), rois(6,3)])];

                    
                    %% Check for identified axis limits
                    xaxisvals = cellfun(@(x) str2double(x), ocrResults(5).Words,'UniformOutput', false);
                    xaxisvals = [xaxisvals{:}];
                    
                    yaxisvals = cellfun(@(x) str2double(x), ocrResults(6).Words,'UniformOutput', false);
                    yaxisvals = [yaxisvals{:}];
                    
                    alternates = [max(yaxisvals), min(yaxisvals), min(xaxisvals), max(xaxisvals)];
                    
                    useable = cellfun(@(x) ~isempty(x), {ocrResults(1:4).Words});
                    for i = 1:4
                        if useable(i) == 1
                            val = str2double(ocrResults(i).Words);
                        else
                            val = alternates(i);                                
                        end
                        
                        if ~isnan(val)
                            switch i
                                case 1
                                    app.MaxYValueEditField.Value = val;
                                    app.MaxYValueEditField.FontColor = [1 0 0];
                                case 2
                                    app.MinYValueEditField.Value = val;
                                    app.MinYValueEditField.FontColor = [1 0 0];
                                case 3
                                    app.MinXValueEditField.Value = val;
                                    app.MinXValueEditField.FontColor = [1 0 0];
                                case 4
                                    app.MaxXValueEditField.Value = val;
                                    app.MaxXValueEditField.FontColor = [1 0 0];
                            end
                        end
                        
                    end
                    
                    %% Check for identified axis labels
                    Xlabel = intersect(ocrResults(5).Words, app.XaxisDropDown.Items);
                    Ylabel = intersect(ocrResults(7).Words, app.YaxisDropDown.Items);
                    
                    if ~isempty(Xlabel)
                        app.XaxisDropDown.Value = Xlabel;
                        app.XaxisDropDown.FontColor = [1 0 0];
                    end
                    
                    if ~isempty(Ylabel)
                        app.YaxisDropDown.Value = Ylabel;
                        app.YaxisDropDown.FontColor = [1 0 0];
                    end
                    
                    %% Check for identified unit prefixes
                    Xunit = ocrResults(5).Words(~cellfun(@isempty,regexp(ocrResults(5).Words,'\(.+\)')));
                    if ~isempty(Xunit)
                        Xunit = strrep(Xunit, '(','');
                        Xunit = strrep(Xunit, ')','');
                        Xunit = Xunit{end}(1);
                        Xunit = intersect(app.XSIprefixDropDown.Items, Xunit);
                    end
                        
                    Yunit = ocrResults(7).Words(~cellfun(@isempty,regexp(ocrResults(7).Words,'\(.+\)')));
                    if ~isempty(Yunit)
                        Yunit = strrep(Yunit, '(','');
                        Yunit = strrep(Yunit, ')','');
                        Yunit = Yunit{end}(1);
                        Yunit = intersect(app.XSIprefixDropDown.Items, Yunit);
                    end
                    
                    if ~isempty(Xunit)
                        app.XSIprefixDropDown.Value = Xunit;
                        app.XSIprefixDropDown.FontColor = [1 0 0];
                    end
                    
                    if ~isempty(Yunit)
                        app.YSIprefixDropDown.Value = Yunit;
                        app.YSIprefixDropDown.FontColor = [1 0 0];
                    end

    
                    Iocr = insertObjectAnnotation(mask, 'rectangle', ...
                               rois, {'Ymax', 'Ymin', 'Xmin', 'Xmax', 'Xlabel', 'Ylabel'}, ...
                                'Color', 'red');
                    app.plotMask = double(Iocr) - double(app.plotOrigImage);
                    if sum(useable) > 0
%                         validResults = ocrResults(useable ==1);
%                         Iocr = insertObjectAnnotation(Iocr, 'rectangle', ...
%                                    validResults.WordBoundingBoxes, ...
%                                    validResults.WordConfidences);
%                         app.plotMask = double(Iocr) - double(app.plotOrigImage);
%                         
                    end
                    
                catch ME
                    warning("OCR failed, attempting to continue.")
                    getReport(ME)
                end
                end
                app.showPlot();
                app.checkValidForDigitizing();
                
            end
        end
        
        function showDatasheetPage(app)

            img = uint8(double(app.pdfOrigImage) + app.pdfMask);
            img_ar = size(img,1)/size(img,2); %h/w
            
            if app.settings.useImageToolbox
                fig_ar = app.pdfAxes.Position(4)/app.pdfAxes.Position(3);
                if abs(img_ar - fig_ar) > 1/app.pdfAxes.Position(3)
                    %% Aspect ratio is off -- correct it
                    if img_ar > fig_ar %img is too tall -- reduce width
                        app.pdfAxes.Position(3) = round(app.pdfAxes.Position(4)/img_ar);
                    else
                        app.pdfAxes.Position(4) = round(app.pdfAxes.Position(3)*img_ar);
                    end
                end
                I = imshow(img, 'Parent', app.pdfAxes);
                app.pdfAxes.XLim = I.XData;
                app.pdfAxes.YLim = I.YData;
                h = drawrectangle(app.pdfAxes, 'Position', [.25*size(app.pdfOrigImage, [ 1 2]), .5*size(app.pdfOrigImage, [ 1 2])]); 
                h.LineWidth = 1;
                addprop(h, 'callingApp');
                h.callingApp = app;
                addlistener(h, 'ROIMoved', @pdfSelectionRectMoved);
                app.pdfSelectionRectMoved(h);
           
            else
                fig_ar = app.pdfImage.Position(4)/app.pdfImage.Position(3);
                if abs(img_ar - fig_ar) > 1/app.pdfImage.Position(3)
                    %% Aspect ratio is off -- correct it
                    if img_ar > fig_ar %img is too tall -- reduce width
                        app.pdfImage.Position(3) = round(app.pdfImage.Position(4)/img_ar);
                    else
                        app.pdfImage.Position(4) = round(app.pdfImage.Position(3)*img_ar);
                    end
                end
                app.pdfImage.ImageSource = img;
            end
        end
        
        function showPlot(app)

            img = uint8(double(app.plotOrigImage) + app.plotMask);
            img_ar = size(img,1)/size(img,2); %h/w

            fig_ar = app.plotImage.Position(4)/app.pdfImage.Position(3);
            if abs(img_ar - fig_ar) > 1/app.plotImage.Position(3)
                %% Aspect ratio is off -- correct it
                if img_ar > fig_ar %img is too tall -- reduce width
                    app.plotImage.Position(3) = round(app.plotImage.Position(4)/img_ar);
                else
                    app.plotImage.Position(4) = round(app.plotImage.Position(3)*img_ar);
                end
            end
            app.plotImage.ImageSource = img;
        end
        
        function checkValidForDigitizing(app)
            if app.MaxYValueEditField.Value > app.MinYValueEditField.Value &&...
                    app.MaxXValueEditField.Value > app.MinXValueEditField.Value && ...
                    ~isempty(app.plotOrigImage)
                app.StartDigitizingButton.Enable = "on";
            else
                app.StartDigitizingButton.Enable = "off";
            end
        end
        
        function resetPlot(app)
            app.MaxYValueEditField.Value = 0; app.MaxYValueEditField.FontColor = [0 0 0];
            app.MinYValueEditField.Value = 0; app.MinYValueEditField.FontColor = [0 0 0];
            app.MaxXValueEditField.Value = 0; app.MaxXValueEditField.FontColor = [0 0 0];
            app.MinXValueEditField.Value = 0; app.MinXValueEditField.FontColor = [0 0 0];
            
            app.YaxisDropDown.Value = '<not selected>'; app.YaxisDropDown.FontColor = [0 0 0];
            app.XaxisDropDown.Value = '<not selected>'; app.XaxisDropDown.FontColor = [0 0 0];
            app.XSIprefixDropDown.Value = 'none'; app.XSIprefixDropDown.FontColor = [0 0 0];
            app.YSIprefixDropDown.Value = 'none'; app.YSIprefixDropDown.FontColor = [0 0 0];
        end

        function [trace, ximg] = colorClustering(app)
            img = app.plotOrigImage(app.imgAxes(4):app.imgAxes(3), app.imgAxes(1):app.imgAxes(2), :);

            %% Get rid of all uncolored pixels (gridlines, axes, labels, etc.)
            hsv = rgb2hsv(img);
            greyscale = uint8(hsv(:,:,2) < .05);
            img(:,:,1) = img(:,:,1) + (255 - img(:,:,1)).*greyscale;
            img(:,:,2) = img(:,:,2) + (255 - img(:,:,2)).*greyscale;
            img(:,:,3) = img(:,:,3) + (255 - img(:,:,3)).*greyscale;
            
            %%
            if app.NTRacesEditField.Value == 0
                hsv = rgb2hsv(img);
                [binCount,~] = histcounts(hsv(:,:,1));
                binCount = sort(binCount, 'descend');
                binCoverage = cumsum(binCount)/(size(hsv,1)*size(hsv,2));
                nColors = find(binCoverage > 0.99,1,'first');
                
                nColors = min(max(nColors,2),10);
                app.NTRacesEditField.Value = nColors-1;
                app.NTRacesEditField.FontColor = [1 0 0];
            else
                nColors = app.NTRacesEditField.Value+1;
                app.NTRacesEditField.FontColor = [0 0 0];
            end
            
%             lab = rgb2lab(img);
%             ab = lab(:,:,2:3);
%             ab = im2single(ab);
            pixel_labels = imsegkmeans(img,nColors,'NumAttempts',3, 'Threshold', 1e-6);
            
            
            colors = zeros(nColors,3);
            timesChanged = zeros(nColors, size(img,2));
            for i = 1:nColors
                R = img(:,:,1);
                G = img(:,:,2);
                B = img(:,:,3);
                colors(i,:) = [ mean(R(pixel_labels==i)), mean(G(pixel_labels==i)), mean(B(pixel_labels==i))];
                timesChanged(i,:) = sum(abs(diff(pixel_labels == i,1)),1);
            end
            [~,worstCandidates] = sort(sum((timesChanged == 2) - (timesChanged > 2),2));
            bestCandidates = flip(worstCandidates);
            
            white = find(sum(colors,2) > 230*3);
            if ~isempty(white)
                [~, IA, ~] = intersect(bestCandidates, white);
                bestCandidates(IA) = [];
            end
            
            ximg = round(linspace(1, size(img,2), 50))';
            trace = nan*ones(50,length(bestCandidates));
            for i = 1:length(bestCandidates)
                src = pixel_labels==bestCandidates(i);
                filt = movmean(src,5);

%                 imshow(filt)
                
                %% Alternate attempt
                % find the middle of the widest section of clear line
%                 intWindow = round(size(img,2)/10);
%                 intFind = movmean([zeros(intWindow,1); ...
%                     1*(timesChanged(bestCandidates(i),:)'==2); ...
%                     zeros(intWindow,1)], intWindow);
%                 intFind = squeeze(intFind(intWindow+1:end-intWindow)).*timesChanged(bestCandidates(i),:)==2';
%                 startLoc = find(intFind(ximg) == max(intFind(ximg)),1);
                preInt = round(size(img,2)/2);
                h = 1:preInt;
                h = [h, flip(h)]/max(h);
                
                goodLoc = 1*(timesChanged(bestCandidates(i),:)'==2);
                
                
                %% Seems like sometimes this is a row, sometimes a column...
                if (size(goodLoc,1) == 1) && (size(h,1) == 1)
                    filtFind = conv(h,goodLoc);
                    filtFind = filtFind(preInt:end-preInt).*goodLoc;
                else
                    filtFind = conv(h,goodLoc');
                    filtFind = filtFind(preInt:end-preInt).*goodLoc';
                end
                
                startLoc = find(filtFind(ximg) == max(filtFind(ximg)),1);
                
                yimg = nan*ones(1, 50);
                xloc = ximg(startLoc);
                loc = find(filt(:,xloc) == max(filt(:,xloc)));
                if length(loc) > 1
                    loc = loc(round(length(loc)/2));
                    yimg(startLoc) = loc;
                elseif length(loc) == 1
                    yimg(startLoc) = loc;
                elseif isempty(loc) || max(filt(:,xloc)) < 0.01
                    yimg(startLoc) = NaN;
                end
                
                for j = startLoc+1:49
                    %% going right
                    xloc = ximg(j);
                    loc = find((filt(:,xloc) == max(filt(:,xloc))) & (filt(:,xloc)>0));
                    yjump = abs(loc - yimg(j-1));
                    if(j>2)
                        if ~isnan(yimg(j-2))
                            % if data is available, use slope projection
                            yjump = abs( loc - 2*yimg(j-1) + yimg(j-2) );  
                        end
                    end
                    
                    if length(loc) > 1 && ~isnan(yimg(j-1))
                        if min(yjump) < size(img,1)*.2
                            yimg(j) = loc(yjump == min(yjump));
                        end
                    elseif length(loc) == 1 && ~isnan(yimg(j-1))
                        if min(yjump) < size(img,1)*.2
                            yimg(j) = loc;
                        end
                    else
                        continue;
                    end
                end
                
                for j = flip(1:startLoc)
                    %% going left
                    xloc = ximg(j);
                    loc = find((filt(:,xloc) == max(filt(:,xloc))) & (filt(:,xloc)>0));
                    yjump = abs(loc - yimg(j+1));
                    if j < length(ximg)-2
                        if ~isnan(yimg(j+2))
                            % if data is available, use slope projection
                            yjump = abs( loc - 2*yimg(j+1) + yimg(j+2) ); 
                        end
                    end
                    
                    if length(loc) > 1 && ~isnan(yimg(j+1))
                        if min(yjump) < size(img,1)*.2
                            yimg(j) = loc(yjump == min(yjump));
                        end
                    elseif length(loc) == 1 && ~isnan(yimg(j+1))
                        if min(yjump) < size(img,1)*.2
                            yimg(j) = loc;
                        end
                    else
                        continue;
                    end
                end
                trace(:,i) = yimg;
            end
        end
        
        function showDigitizedDataTable(app, dP)
            if ~isprop(app.digitizedResultsTable, 'dP')
                addprop(app.digitizedResultsTable, 'dP');
            end
            app.digitizedResultsTable.dP = dP;
                    
            app.digitizedResultsTable.Visible = "on";
            app.digitizedResultsDoneButton.Visible = "on";
            app.digitizedResultsTable.Data = [];
            
            Data = [];
                        
            for i = 1:length(dP.plotData)
                app.digitizedResultsTable.ColumnName{2*i} = dP.dataLabels{i};
                app.digitizedResultsTable.ColumnName{2*i-1} = 'X';
                
                curLength = size(Data,1);
                nVals = size(dP.plotData{i},1);
                Data(1:nVals, 2*i-1:2*i) = dP.plotData{i};
                if i > 1
                    if nVals > curLength
                        Data(curLength+1:nVals,1:2*(i-1)) = NaN;
                    elseif curLength > nVals
                        Data(nVals+1:curLength, 2*i-1:2*i) = NaN;
                    end
                end
            end 
            app.digitizedResultsTable.Data = Data;
            app.digitizedResultsTable.ColumnEditable = ones(1, 2*length(dP.plotData))==1;
        end
    end
    
    methods (Access = public)
        
        function grabitComplete(app, handle)
%             app.showGrabitData(handle);
             
            %% Load data
            digiPlot = digitizedPlot(app);
            digiPlot.loadGrabitData(handle);
            
            %% Plot Digitized Data over figure
            cla(app.plotAxes); app.digiTracesTable.Data = [];
            app.plotAxes.Position = app.plotImage.Position;
            digiPlot.plotDigitizedData(app.plotAxes)
            
            app.showDigitizedDataTable(digiPlot);


        end
        
        function reinitialize(app)
%             app.createComponents();
            app.initialize(app.datasheet);
        end
        
        
        function pdfSelectionRectMoved(app, rect)
            img = app.pdfOrigImage;
            plot = img(round(rect.Position(2)):round(sum(rect.Position([2 4]))), ...
                round(rect.Position(1)):round(sum(rect.Position([1 3]))), :);
            app.plotOrigImage = plot;
            app.plotAxes.Position(4) = 0;
            app.plotMask = 0;
            app.showPlot();
            app.identifyPlot();
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function initialize(app, datasheet)
            eloc = mfilename('fullpath'); %executing location
            divs = strfind(eloc, '\');
            eloc = eloc(1:divs(end));
            cd(eloc)
            if ~isfolder('temp')
                mkdir(eloc, 'temp');
            end
            if ~isfolder('settings')
                mkdir(eloc, 'settings');
            end
            addpath("temp");
            addpath("settings");
            addpath("src");

            % [flist, plist] = matlab.codetools.requiredFilesAndProducts('plotDigitizer.mlapp') 
            
            %% Make plotAxes invisible
            app.plotAxes.Position(4) = 0;


            if(nargin < 2)
                % No file given
                app.dialogWait = 1;
                datasheetDialog(app);
                while (app.dialogWait == 1)
                    pause(1)
                end
            else
                app.datasheet = datasheet;
            end
            
            if ~exist("digitizerSetup.mat",'file')
                PDpreferences(app);
                while(~exist("digitizerSetup.mat",'file'))
                    pause(1);
                end
                load("digitizerSetup.mat", 'PDsettings');
            else
                load("digitizerSetup.mat", 'PDsettings');
            end
            
            assert(exist("PDsettings","var")==1, 'settings file not available');       
            
            app.settings = PDsettings;    %store settings loaded from mat file

            assert(isfield(app.settings, 'gsloc'), 'ghostscript location not given');
            assert(isfile(app.settings.gsloc), 'ghoscript file is incorrect')
            
            if ~isfield(app.settings, 'clargs')
                app.settings.clargs = '-sDEVICE=pngalpha -r300 -o';
            end
            
            if ~isfield(app.settings, 'useCVToolbox')
                app.settings.useCVToolbox = 0;
            end
            
            if ~isfield(app.settings, 'useImageToolbox')
                app.settings.useImageToolbox = 0;
            end
            
            %% Check MATLAB version and installed Toolboxes
            hasImageToolbox = license('test','image_toolbox');
            hasComputerVision = license('test','Video_and_Image_Blockset');
            MLver = version;
            [si, ei] = regexp(MLver, '\(.*\)');
            MLversion = {str2double(MLver(si+2:ei-2)), MLver(ei-1)};
            
            canUseRectSelection = (MLversion{1} > 2019 || ...
                ((MLversion{1} == 2019) && MLversion{2} == 'b')) ...
                && hasImageToolbox;
            
           if ~canUseRectSelection
               app.settings.useImageToolbox = 0;
           end
           
           if ~hasComputerVision
               app.settings.useCVToolbox = 0;
           end
            
            assert(prod(isfield(app.settings, {'gsloc', 'clargs', ...
                'useImageToolbox', 'useCVToolbox'}))==1, ...
                'Improperly formatted settings file');
            
            PDsettings = app.settings;
            save("settings\digitizerSetup.mat", 'PDsettings');
            
            %% Alter items shown based on settings
            app.pdfAxes.Visible = app.settings.useImageToolbox;
            app.pdfImage.Visible = ~app.settings.useImageToolbox;
            
            if app.settings.useImageToolbox == 1
                app.pdfAxes.Visible = "on";
                app.pdfAxes.Position = [35,215,330,427];
                app.pdfImage.Visible = "off";
                app.pdfImage.Position = [35 215 0 0];
            else
                app.pdfAxes.Visible = "off";
                app.pdfAxes.Position = [35 215 0 0];
                app.pdfImage.Visible = "on";
                app.pdfImage.Position = [35,215,330,427];
            end

            app.loadDatasheetPDF();
        end

        % Button pushed function: prevButton
        function prevButtonPushed(app, event)
            if(isempty(app.pdfFiles))
                app.findPDFfiles();
            end
            app.pdfIndex = app.pdfIndex - 1;
            if(app.pdfIndex <= 0)
                app.pdfIndex = length(app.pdfFiles);
            end
            app.pdfOrigImage = imread(app.pdfFiles{app.pdfIndex});
            app.showDatasheetPage();
        end

        % Image clicked function: pdfImage
        function pdfClick(app, event)
           clickLoc = get(0, 'PointerLocation'); % Origin is bottom-left
           windowLoc = app.DatasheetDigitizerUIFigure.Position; %bottom-left corner of figure from bottom-left corner of screen
           figureLoc = app.pdfImage.Position;  %bottom-left from bottom-left
           
           clickOnFigure = clickLoc - windowLoc(1:2);
           clickOnImage = clickOnFigure - figureLoc(1:2);
           
           app.clickTimer = tic;

           app.pdfClicks = [app.pdfClicks; clickOnImage./figureLoc(3:4)];


           if sum(size(app.pdfClicks) == [2,2]) == 2
               app.plotSelectionFromPDF();
               app.pdfClicks = [];
           end
        end

        % Image clicked function: plotImage
        function plotClick(app, event)
            
        end

        % Button pushed function: nextButton
        function nextButtonPushed(app, event)
            if(isempty(app.pdfFiles))
                app.findPDFfiles();
            end
            app.pdfIndex = app.pdfIndex + 1;
            if(app.pdfIndex > length(app.pdfFiles))
                app.pdfIndex = 1;
            end
            app.pdfOrigImage = imread(app.pdfFiles{app.pdfIndex});
            app.showDatasheetPage();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.DatasheetDigitizerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {715, 715};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {399, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: StartDigitizingButton
        function startDigitizer(app, event)
            if ~isempty(app.plotOrigImage)
                imwrite(app.plotOrigImage, 'temp/tempPlot.png');

                grabit([pwd '\' 'temp\tempPlot.png'], app);
            end
        end

        % Window button up function: DatasheetDigitizerUIFigure
        function clickRelease(app, event)
           clickLoc = get(0, 'PointerLocation'); % Origin is bottom-left
           holdTime = toc;
           app.clickTimer = [];
           
           windowLoc = app.DatasheetDigitizerUIFigure.Position; %bottom-left corner of figure from bottom-left corner of screen
           figureLoc = app.pdfImage.Position;  %bottom-left from bottom-left
           
           clickOnFigure = clickLoc - windowLoc(1:2);
           clickOnImage = clickOnFigure - figureLoc(1:2);
           
           if (sum(clickOnImage > 0) == 2) && (sum(clickOnImage - figureLoc(3:4) < 0) == 2) && (holdTime > 0.1)
                app.pdfClicks = [app.pdfClicks; clickOnImage./figureLoc(3:4)];

               if sum(size(app.pdfClicks) == [2,2]) == 2
                   app.plotSelectionFromPDF();
                   app.pdfClicks = [];
               end
           
           end
        end

        % Callback function
        function test(app, event)
            
        end

        % Menu selected function: PreferencesMenu
        function filePreferences(app, event)
            app.dialogWait = 1;
            PDpreferences(app);
            while app.dialogWait == 1
                pause(1)
            end         
        end

        % Menu selected function: NewMenu
        function fileNew(app, event)
            app.dialogWait = 1;
            datasheetDialog(app);
            while app.dialogWait == 1
                pause(1)
            end
            app.loadDatasheetPDF();
        end

        % Value changed function: MaxXValueEditField, 
        % MaxYValueEditField, MinXValueEditField, MinYValueEditField
        function plotFieldEdited(app, event)
            event.Source.FontColor = [0 0 0];
            
            app.checkValidForDigitizing();
        end

        % Button pushed function: AutoDigitizebyColorButton
        function digitizeColoredPlot(app, event)
            if app.settings.useImageToolbox
            try
                [trace, ximg] = app.colorClustering();
                
                if ~isempty(trace)
                    %% reset Table and Axes
                    cla(app.plotAxes); app.digiTracesTable.Data = [];
                    
                    %% Plot Digitized Data over figure
                    
                    app.plotAxes.Position = app.plotImage.Position;
                        
%                     hold(app.plotAxes, 'off');
%                     imshow(app.plotOrigImage, 'Parent', app.plotAxes);
%                     hold(app.plotAxes, 'on');
%                     for i = 1:size(trace,2)
%                         plot(app.plotAxes, app.imgAxes(1) + ximg(~isnan(trace(:,i))), ...
%                             app.imgAxes(4) + trace(~isnan(trace(:,i)),i), 'Linewidth',3);
%                     end
                    digiPlot = digitizedPlot(app);
                    digiPlot.loadAutoColorDigitizedData(ximg, trace);
                    digiPlot.plotDigitizedData(app.plotAxes);
                    
                    app.showDigitizedDataTable(digiPlot);
                    
                end
                
            catch ME
                warning("Automatic plot digitization based on color failed");
                getReport(ME)
            end
            end
        end

        % Button pushed function: digitizedResultsDoneButton
        function digitizedResultsEditingDone(app, event)
            app.digitizedResultsTable.Visible = "off";
            app.digitizedResultsDoneButton.Visible = "off";
            app.NTRacesEditField.Value = 0;
        end

        % Cell selection callback: digitizedResultsTable
        function showTableCellOnPlot(app, event)
%             delete(app.displayPoints);
%             indices = event.Indices;
%             xo = indices(:,2)==1;
%             x = app.digitizedResultsTable.Data(indices(:,1),1);
%             y = zeros(size(indices,1),1);
%             for i = 1:size(indices, 1)
%                 y(i) = app.digitizedResultsTable.Data(indices(i,1),indices(i,2));
%             end
%             app.displayPoints = plot(app.plotAxes, x(~xo) + app.imgAxes(1), y(~xo)+app.imgAxes(4), 'or', 'LineWidth', 3);

            delete(app.displayPoints);
            indices = event.Indices;
            
            dP = app.digitizedResultsTable.dP;
            
            % Ignore any values selected from the x-columns
            xo = mod(indices(:,2),2) == 1;
            indices(xo,:) = [];
            traces = unique(indices(:,2))/2;
            
            if ~isempty(traces)
                % Find corresponding img location for each datapoint
                for i = traces'
                    ind = indices(indices(:,2) == 2*i,:);
                    ind = ind(:,1);
                    xPlotData = app.digitizedResultsTable.Data(ind,i*2-1);
                    
                    [~,locs,~] = intersect(dP.plotData{i}(:,1), xPlotData);
                    ximgData = dP.imgData{i}(locs,1);
                    yimgData = dP.imgData{i}(locs,2);
                    
                    refTrace = findobj(app.plotAxes.Children,'Type','line');
                    traceColor = refTrace(end-i+1).Color;
                    app.displayPoints = plot(app.plotAxes, ximgData , yimgData, 'o', 'LineWidth', 3, 'MarkerEdgeColor', traceColor);
                end
            end

        end

        % Cell edit callback: digitizedResultsTable
        function updateDigizedDataFromTable(app, event)
            indices = event.Indices;
            newData = event.NewData;
            
            dP = app.digitizedResultsTable.dP;
            xy = 2- mod(indices(:,2),2);
            
            for i = 1:size(indices,1)
                ind = indices(i,:);
                trace = ceil(ind(2)/2);
                dP.plotData{trace}(ind(1), xy) = newData(i);
                
%                 refTrace = findobj(app.plotAxes.Children,'Type','line');
            end
            dP.convert(2);
            cla(app.plotAxes);
            dP.plotDigitizedData(app.plotAxes);
            
            app.showTableCellOnPlot(event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DatasheetDigitizerUIFigure = uifigure('Visible', 'off');
            app.DatasheetDigitizerUIFigure.Name = 'Datasheet Digitizer';
            app.DatasheetDigitizerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.DatasheetDigitizerUIFigure.WindowButtonUpFcn = createCallbackFcn(app, @clickRelease, true);
            app.FileMenu = uimenu(app.DatasheetDigitizerUIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @fileNew, true);
            app.NewMenu.Text = 'New';
            app.PreferencesMenu = uimenu(app.FileMenu);
            app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @filePreferences, true);
            app.PreferencesMenu.Text = 'Preferences';
            app.GridLayout = uigridlayout(app.DatasheetDigitizerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.pdfImage = uiimage(app.LeftPanel);
            app.pdfImage.ImageClickedFcn = createCallbackFcn(app, @pdfClick, true);
            app.prevButton = uibutton(app.LeftPanel, 'push');
            app.prevButton.ButtonPushedFcn = createCallbackFcn(app, @prevButtonPushed, true);
            app.prevButton.Text = 'prev';
            app.InstructionsTextAreaLabel = uilabel(app.LeftPanel);
            app.InstructionsTextAreaLabel.Visible = 'off';
            app.InstructionsTextAreaLabel.Text = 'Instructions';
            app.InstructionsTextArea = uitextarea(app.LeftPanel);
            app.PartNumberEditFieldLabel = uilabel(app.LeftPanel);
            app.PartNumberEditFieldLabel.Text = 'Part Number';
            app.PartNumberEditField = uieditfield(app.LeftPanel, 'text');
            app.nextButton = uibutton(app.LeftPanel, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.nextButton.Text = 'next';
            app.DatasheetLabel = uilabel(app.LeftPanel);
            app.DatasheetLabel.Text = 'Datasheet';
            app.pdfAxes = uiaxes(app.LeftPanel);
            title(app.pdfAxes, '')
            xlabel(app.pdfAxes, '')
            ylabel(app.pdfAxes, '')
            app.pdfAxes.XTick = [];
            app.pdfAxes.YTick = [];
            app.digitizedResultsTable = uitable(app.LeftPanel);
            app.digitizedResultsTable.ColumnName = {'X'; 'Y1'};
            app.digitizedResultsTable.RowName = {};
            app.digitizedResultsTable.CellEditCallback = createCallbackFcn(app, @updateDigizedDataFromTable, true);
            app.digitizedResultsTable.CellSelectionCallback = createCallbackFcn(app, @showTableCellOnPlot, true);
            app.digitizedResultsTable.Visible = 'off';
            app.digitizedResultsDoneButton = uibutton(app.LeftPanel, 'push');
            app.digitizedResultsDoneButton.ButtonPushedFcn = createCallbackFcn(app, @digitizedResultsEditingDone, true);
            app.digitizedResultsDoneButton.Visible = 'off';
            app.digitizedResultsDoneButton.Text = 'Done';
            app.RightPanel = uipanel(app.GridLayout);
            app.plotImage = uiimage(app.RightPanel);
            app.plotImage.ImageClickedFcn = createCallbackFcn(app, @plotClick, true);
            app.StartDigitizingButton = uibutton(app.RightPanel, 'push');
            app.StartDigitizingButton.ButtonPushedFcn = createCallbackFcn(app, @startDigitizer, true);
            app.StartDigitizingButton.Enable = 'off';
            app.StartDigitizingButton.Text = 'Start Digitizing';
            app.XaxisDropDownLabel = uilabel(app.RightPanel);
            app.XaxisDropDownLabel.Text = 'X-axis';
            app.XaxisDropDown = uidropdown(app.RightPanel);
            app.XaxisDropDown.Items = {'<not selected>', 'Capacitance', 'Voltage', 'Current', 'Resistance', 'Energy', 'Charge', 'Temperature', 'Thermal Resistance', 'Time'};
            app.XaxisDropDown.Value = '<not selected>';
            app.YaxisDropDownLabel = uilabel(app.RightPanel);
            app.YaxisDropDownLabel.Text = 'Y-axis';
            app.YaxisDropDown = uidropdown(app.RightPanel);
            app.YaxisDropDown.Items = {'<not selected>', 'Capacitance', 'Voltage', 'Current', 'Resistance', 'Energy', 'Charge', 'Temperature', 'Thermal Resistance', 'Time', ''};
            app.YaxisDropDown.Value = '<not selected>';
            app.SIprefixDropDown_2Label = uilabel(app.RightPanel);
            app.SIprefixDropDown_2Label.Text = 'SI prefix';
            app.XSIprefixDropDown = uidropdown(app.RightPanel);
            app.XSIprefixDropDown.Items = {'f', 'p', 'n', 'u', 'm', 'none', 'k', 'M', 'G'};
            app.XSIprefixDropDown.Value = 'none';
            app.NormalizedYaxisCheckBox = uicheckbox(app.RightPanel);
            app.NormalizedYaxisCheckBox.Text = 'Normalized  Y-axis';
            app.LogarithmicYaxisCheckBox = uicheckbox(app.RightPanel);
            app.LogarithmicYaxisCheckBox.Text = 'Logarithmic Y-axis';
            app.NormalizedXaxisCheckBox = uicheckbox(app.RightPanel);
            app.NormalizedXaxisCheckBox.Text = 'Normalized  X-axis';
            app.LogarithmicXaxisCheckBox = uicheckbox(app.RightPanel);
            app.LogarithmicXaxisCheckBox.Text = 'Logarithmic X-axis';
            app.MaxYValueEditFieldLabel = uilabel(app.RightPanel);
            app.MaxYValueEditFieldLabel.Text = 'Max Y-Value';
            app.MaxYValueEditField = uieditfield(app.RightPanel, 'numeric');
            app.MaxYValueEditField.ValueChangedFcn = createCallbackFcn(app, @plotFieldEdited, true);
            app.MinYValueEditFieldLabel = uilabel(app.RightPanel);
            app.MinYValueEditFieldLabel.Text = 'Min Y-Value';
            app.MinYValueEditField = uieditfield(app.RightPanel, 'numeric');
            app.MinYValueEditField.ValueChangedFcn = createCallbackFcn(app, @plotFieldEdited, true);
            app.MaxXValueEditFieldLabel = uilabel(app.RightPanel);
            app.MaxXValueEditFieldLabel.Text = 'Max X-Value';
            app.MaxXValueEditField = uieditfield(app.RightPanel, 'numeric');
            app.MaxXValueEditField.ValueChangedFcn = createCallbackFcn(app, @plotFieldEdited, true);
            app.MinXValueEditFieldLabel = uilabel(app.RightPanel);
            app.MinXValueEditFieldLabel.Text = 'Min X-Value';
            app.MinXValueEditField = uieditfield(app.RightPanel, 'numeric');
            app.MinXValueEditField.ValueChangedFcn = createCallbackFcn(app, @plotFieldEdited, true);
            app.skewedaxesCheckBox = uicheckbox(app.RightPanel);
            app.skewedaxesCheckBox.Enable = 'off';
            app.skewedaxesCheckBox.Text = 'skewed axes';
            app.digiTracesTable = uitable(app.RightPanel);
            app.digiTracesTable.ColumnName = {'Trace'; 'Data'; 'Test Conditions'};
            app.digiTracesTable.RowName = {};
            app.digiTracesTable.ColumnEditable = [true false true];
            app.SIprefixDropDown_2Label_2 = uilabel(app.RightPanel);
            app.SIprefixDropDown_2Label_2.Text = 'SI prefix';
            app.YSIprefixDropDown = uidropdown(app.RightPanel);
            app.YSIprefixDropDown.Items = {'f', 'p', 'n', 'u', 'm', 'none', 'k', 'M', 'G'};
            app.YSIprefixDropDown.Value = 'none';
            app.plotAxes = uiaxes(app.RightPanel);
            title(app.plotAxes, '')
            xlabel(app.plotAxes, '')
            ylabel(app.plotAxes, '')
            app.plotAxes.XTick = [];
            app.plotAxes.YTick = [];
            app.AutoDigitizebyColorButton = uibutton(app.RightPanel, 'push');
            app.AutoDigitizebyColorButton.ButtonPushedFcn = createCallbackFcn(app, @digitizeColoredPlot, true);
            app.AutoDigitizebyColorButton.Text = 'Auto-Digitize by Color';
            app.ofTracesLabel = uilabel(app.RightPanel);
            app.ofTracesLabel.Text = '# of Traces';
            app.NTRacesEditField = uieditfield(app.RightPanel, 'numeric');
            % Show the figure after all components are created
            app.DatasheetDigitizerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotDigitizerMessedUp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DatasheetDigitizerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)initialize(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DatasheetDigitizerUIFigure)
        end
    end
end